classdef Addcell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddcellUI                matlab.ui.Figure
        UIGridLayout             matlab.ui.container.GridLayout
        ImagePanel               matlab.ui.container.Panel
        ImageGridLayout          matlab.ui.container.GridLayout
        ImageAxes                matlab.ui.control.UIAxes
        ToolsPanel               matlab.ui.container.Panel
        ToolsPanelGridLayout     matlab.ui.container.GridLayout
        DisplayPanel             matlab.ui.container.Panel
        DisplayPanelGridLayout   matlab.ui.container.GridLayout
        DisplayLabel             matlab.ui.control.Label
        MasksGridLayout          matlab.ui.container.GridLayout
        AButton                  matlab.ui.control.StateButton
        BButton                  matlab.ui.control.StateButton
        FlagMaskButton           matlab.ui.control.StateButton
        MultiNucMaskButton       matlab.ui.control.StateButton
        AppliedButton            matlab.ui.control.StateButton
        ImageColorsGridLayout    matlab.ui.container.GridLayout
        ShowMemButton            matlab.ui.control.StateButton
        MEMButton                matlab.ui.control.StateButton
        DAPIButton               matlab.ui.control.StateButton
        BrightnessSliderLabel    matlab.ui.control.Label
        BrightnessSlider         matlab.ui.control.Slider
        ContrastSliderLabel      matlab.ui.control.Label
        ContrastSlider           matlab.ui.control.Slider
        DrawButton               matlab.ui.control.StateButton
        AcceptButton             matlab.ui.control.StateButton
        RejectButton             matlab.ui.control.StateButton
        MultiNucButton           matlab.ui.control.StateButton
        FlagButton               matlab.ui.control.StateButton
        TrashButton              matlab.ui.control.StateButton
        ObjectPlaneSpinnerLabel  matlab.ui.control.Label
        ObjectPlaneSpinner       matlab.ui.control.Spinner
    end

    properties (Access = public)
        %% -----------------------------------------------
        % table variables for the add cell window
        %
        overlap_table % table of the cells
        draw_im % image for drawn cells 
        paired_cells % matlab cell struct where each stores value stores
        % linear indicies of the respective cell
        %% -----------------------------------------------
        % variables that identify the current cell object 
        %
        current_cell_table_index;
        cells_added_count = -1; % number of cells added during the image 
        % review
        %% -----------------------------------------------
        % for image display
        %
        ImageAxes_; % the image inside the image axes
        im_xlim = 0; % x limits when displaying the image
        im_ylim = 0; % y limits when displaying the image
        stored_axes_position = [0 0 0 0];
        %
    end
    %
    properties (Access = private)
        %% -----------------------------------------------
        seg_tool_base % main app object
    end
    %
    methods (Access = private)
        %
        function recompile_and_display_add_cell(app)
            %%
            % recompile and display the cell segmentation
            %%
            %
            compile_applied_seg_mask(app.seg_tool_base, app)
            compile_flag_masks(app.seg_tool_base, app)
            %
            if app.AppliedButton.Value
                display_images_add_cell(app)
            end
            %
        end
        %
        function display_images_add_cell(app)
            %%
            % calls the image display, storing the x and y limits 
            % before displaying to reset them. Separated from the imshow
            % b/c matlab was having some underlying bug when the storage
            % and the show were in the same function.
            %%
            app.im_xlim = xlim(app.ImageAxes);
            app.im_ylim = ylim(app.ImageAxes);
            %
            imshow_add_cell(app)
            %
        end
        %
        function imshow_add_cell(app)
            %%
            % this function is used to change the cell show as well as its
            % apperance
            %%
            im = get_color_image(app.seg_tool_base, ...
                app.ShowMemButton.Value);
            %
            im = handle_display_masks(app, im);
            %
            app.seg_tool_base.imshow_base(app, im)
            %
        end
        %
        function im = handle_display_masks(app, im)
            %%
            % handle the masks for the display image
            %%
            im = handle_segmentation_masks(app, im);
            im = handle_flag_masks(app, im);
            im = uint8(im);
            %
        end
        %
        function im = handle_segmentation_masks(app, im)
            %%
            % handles which segmentation masks are applied to the images
            % based on the buttons in the UI
            %%
            seg = [];
            %
            if app.BButton.Value
                %
                seg = app.seg_tool_base.(...
                    ['segmentation_image_', app.seg_tool_base.B]);
                %
            elseif app.AButton.Value
                %
                seg = app.seg_tool_base.(...
                    ['segmentation_image_', app.seg_tool_base.A]);
                %
            elseif app.AppliedButton.Value
                %
                seg = app.seg_tool_base.applied_seg_mask;
                %
            end
            %
            im = apply_mask_colors(app.seg_tool_base, im, seg);
            %
        end
        %
        function im = handle_flag_masks(app, im)
            %%
            % handle display for masks that rely on the flags
            %%
            seg = [];
            %
            if app.FlagMaskButton.Value 
                %
                seg = app.seg_tool_base.Flag_mask;
                %
            elseif app.MultiNucMaskButton.Value 
                %
                seg = app.seg_tool_base.MultiNuc_mask;
                %
            end
            %
            im = apply_mask_colors(app.seg_tool_base, im, seg);
            %
        end
        %
        function do_trash(app)
            %%
            % allow the user to select a cell to delete and handle all 
            % buttons
            %%
            trash = app.TrashButton.Value;
            %
            handle_button_interrupt(app)
            %
            if ~trash
                return
            end
            %
            handle_button_click(app.seg_tool_base, app.TrashButton,'on')               
            %
            apply_seg(app)
            %
            delete_object(app.seg_tool_base, app)
            %
            recompile_and_display_add_cell(app)
            %
            handle_button_click(app.seg_tool_base, app.TrashButton,'off')
            %
        end
        %
        function do_flag(app, button_id)
            %
            button_value = app.([button_id, 'Button']).Value;
            %
            handle_button_interrupt(app)
            %
            if ~button_value
                return
            end
            %
            handle_button_click(app.seg_tool_base, ...
                app.([button_id, 'Button']),'on')
            %
            update_mask_buttons(app.seg_tool_base, app, button_id)
            %
            toggle_seg(app)
            %
            display_images_add_cell(app)
            %
            flag_object(app.seg_tool_base, app, button_id)
            %
            display_images_add_cell(app)
            %
            handle_button_click(app.seg_tool_base, ...
                app.([button_id, 'Button']),'off')
            %
        end
        %
        function apply_seg(app)
            %%
            % turn on the applied segmentation mask 
            %%
            if ~app.AppliedButton.Value
                toggle_seg(app)
                display_images_add_cell(app)
            end
            %
        end
        %
        function toggle_seg(app)
            %%
            % turn on the applied segmentation mask 
            %%
            handle_button_click(app.seg_tool_base, app.AButton,'off')
            handle_button_click(app.seg_tool_base, app.BButton,'off')
            handle_button_click(app.seg_tool_base, app.AppliedButton,'on')
            %
        end
        %
        function reject_and_exit_app(app)
            %%
            % reject cells and exit the add
            %%
            handle_button_interrupt(app)
            handle_button_click(app.seg_tool_base, app.RejectButton, 'on');
            %
            % delete the app do not return anything
            %
            title = 'Confirm "Reject"';
            msg = ['Reject all edits and ',...
               'return to main application?'];
            del_var = uiconfirm(app.AddcellUI,msg,title,...
                'Options',{'Confirm','Cancel'},...
                'DefaultOption',1,'CancelOption',2);
            %
            if strcmp(del_var, 'Confirm')
                delete(app)
            else
                handle_button_click(app.seg_tool_base, app.RejectButton, 'off');
            end
            %
            if strcmp(del_var, 'Confirm')
                %
                % delete the app do not return anything
                %
                delete(app)
                %
            else
                %
                % for cancel, reset reject and return to app
                %
                handle_button_click(app.seg_tool_base, app.RejectButton, 'off');
                %
            end
            %            
        end
        %
        function handle_button_interrupt(app)
            %%
            % resets the current cell object to null and handles button
            % interuption states
            %%
            app.seg_tool_base.esc()
            %
            % removes any data for the 'new' cell
            % if a button is interrupted we don't keep 
            % the drawn object, same for display option
            %
            pairid = app.seg_tool_base.current_cell_pairid_index(app);
            %
            if ~isempty(app.paired_cells(pairid))                  
                app.draw_im (app.draw_im  == pairid) = 0;
                app.paired_cells(pairid) = {0};
                recompile_and_display_add_cell(app)  
            end
            %
            % turn off all interactive buttons
            %
            handle_button_click(app.seg_tool_base, app.DrawButton, 'off')
            handle_button_click(app.seg_tool_base, app.FlagButton,'off')
            handle_button_click(app.seg_tool_base, app.MultiNucButton,'off')
            handle_button_click(app.seg_tool_base, app.TrashButton,'off')
            %
        end
        %
        function handle_segmentation_mask_click(app, button_id)
            %%
            % when a segmentation mask is clicked, turn off all
            % segmentation buttons, if value was turned on turn it on, then
            % redisplay the images 
            %%
            button_value = app.([button_id, 'Button']).Value;
            %
            handle_button_click(app.seg_tool_base, app.AppliedButton,'off')
            handle_button_click(app.seg_tool_base, app.AButton,'off')
            handle_button_click(app.seg_tool_base, app.BButton,'off')
            %
            if button_value
                handle_button_click(app.seg_tool_base, ...
                    app.([button_id, 'Button']),'on')
                %
                if ~strcmp(button_id, 'Applied')
                    handle_button_click(...
                        app.seg_tool_base, app.FlagMaskButton,'off')
                    handle_button_click(...
                        app.seg_tool_base, app.MultiNucMaskButton,'off')
                end
                %
            else
                %
                handle_button_click(...
                    app.seg_tool_base, app.FlagMaskButton,'off')
                handle_button_click(...
                    app.seg_tool_base, app.MultiNucMaskButton,'off')
                %
            end
            %
            display_images_add_cell(app)
            %
        end
        %
        function handle_flag_mask_click(app, button_id)
            %%
            % when a segmentation mask is clicked, turn off all
            % segmentation buttons, if value was turned on turn it on, then
            % redisplay the images 
            %%
            button_value = app.([button_id, 'MaskButton']).Value;
            %
            handle_button_click(...
                app.seg_tool_base, app.FlagMaskButton,'off')
            handle_button_click(...
                app.seg_tool_base, app.MultiNucMaskButton,'off')
            %
            if button_value
                handle_button_click(app.seg_tool_base, ...
                    app.([button_id, 'MaskButton']),'on')
                toggle_seg(app)
            end
            %
            display_images_add_cell(app)
            %
        end
        %
        function set_active_select_axis_and_ui_add_cell(app)
            %
            app.seg_tool_base.axis_to_select_on = app.ImageAxes;
            app.seg_tool_base.active_ui = app.AddcellUI;
            app.seg_tool_base.active_app = app.seg_tool_base.apps.add_cell;
            %
        end
        %
    end
    %

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupAddcell(app, mainapp)
            %
            % recieve variables from main app
            %
            app.seg_tool_base = mainapp;  
            app.overlap_table = app.seg_tool_base.overlap_table;
            app.paired_cells = app.seg_tool_base.paired_cells;
            app.draw_im = app.seg_tool_base.draw_im;
            %
            % Configure image axes
            %
            app.im_xlim = app.seg_tool_base.bounding_box.cols;
            app.im_ylim = app.seg_tool_base.bounding_box.rows;
            %
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            xlim(app.ImageAxes, app.im_xlim)
            ylim(app.ImageAxes, app.im_ylim)          
            %
            set_active_select_axis_and_ui_add_cell(app)
            %
            draw_ui_instructions(app.seg_tool_base)
            %
            compile_flag_masks(app.seg_tool_base, app)
            %
            handle_button_click(app.seg_tool_base, app.AppliedButton,'on')
            add_cell_row(app.seg_tool_base, app)
            recompile_and_display_add_cell(app)
            %
        end

        % Value changed function: MEMButton
        function MEMButtonValueChanged(app, event)
            %
            MEM_click(app.seg_tool_base, app, 'off')
            %
        end

        % Value changed function: DAPIButton
        function DAPIButtonValueChanged(app, event)
            %
            DAPI_click(app.seg_tool_base, app, 'off')
            %        
        end

        % Value changed function: BrightnessSlider, ContrastSlider
        function ColorSliderValueChanged(app, event)
            %
            apply_image_colors(app.seg_tool_base, app)
            display_images_add_cell(app)
            %
        end

        % Value changed function: ShowMemButton
        function ShowMemButtonValueChanged(app, event)
            %
            if app.ShowMemButton.Value
                handle_button_click(app.seg_tool_base, ...
                    app.ShowMemButton,'on')
            else
                handle_button_click(app.seg_tool_base, ...
                    app.ShowMemButton,'off')
            end
            %
            display_images_add_cell(app)
            %
        end

        % Value changed function: AButton
        function AButtonValueChanged(app, event)
            %
            handle_segmentation_mask_click(app, 'A')
            %
        end

        % Value changed function: BButton
        function BButtonValueChanged(app, event)
            %
            handle_segmentation_mask_click(app, 'B')
            %
        end

        % Value changed function: AppliedButton
        function AppliedButtonValueChanged(app, event)
            %
            handle_segmentation_mask_click(app, 'Applied')
            %
        end

        % Value changed function: FlagMaskButton
        function FlagMaskButtonValueChanged(app, event)
            %
            handle_flag_mask_click(app, 'Flag')
            %
        end

        % Value changed function: MultiNucMaskButton
        function MultiNucMaskButtonValueChanged(app, event)
            %
            handle_flag_mask_click(app, 'MultiNuc')
            %
        end

        % Value changed function: DrawButton
        function DrawButtonValueChanged(app, event)
            %
            button_value = app.DrawButton.Value;
            handle_button_interrupt(app)
            %
            if button_value
                %
                handle_button_click(app.seg_tool_base, app.DrawButton, ...
                    'on')
                do_draw(app.seg_tool_base, app, app.ImageAxes)
                toggle_seg(app)
                add_cell_row(app.seg_tool_base, app)
                recompile_and_display_add_cell(app)
                %
            end
            %
            handle_button_click(app.seg_tool_base, app.DrawButton, 'off')
            %
        end

        % Value changed function: MultiNucButton
        function MultiNucButtonValueChanged(app, event)
            %
            do_flag(app, 'MultiNuc')           
            %
        end

        % Value changed function: FlagButton
        function FlagButtonValueChanged(app, event)
            %
            do_flag(app, 'Flag')
            %
        end

        % Value changed function: TrashButton
        function TrashButtonValueChanged(app, event)
            %
            do_trash(app)
            % 
        end

        % Value changed function: ObjectPlaneSpinner
        function ObjectPlaneSpinnerValueChanged(app, event)
            %
            if app.cells_added_count > 1
                %
                app.overlap_table.ground_level(...
                    app.current_cell_table_index - 1) ...
                    = app.ObjectPlaneSpinner.Value;
                recompile_and_display_add_cell(app)
                %
            else 
                %
                app.ObjectPlaneSpinner.Value = 1;
                %
            end
            %
            handle_button_interrupt(app)
            %
        end

        % Value changed function: AcceptButton
        function AcceptButtonValueChanged(app, event)
            %
            handle_button_interrupt(app)
            handle_button_click(app.seg_tool_base, app.AcceptButton, 'on');
            %
            msg = 'Accept drawn cell segmentation and close?';
            title = 'Confirm "Accept"';
            selection = uiconfirm(app.AddcellUI, msg, title);
            %
            if strcmp(selection,'OK')
                %
                app.paired_cells(...
                    app.seg_tool_base.current_cell_pairid_index(app)) = [];
                app.overlap_table(app.current_cell_table_index, :) = [];
                %
                return_to_seg_base(app.seg_tool_base, app)
                %
                delete(app)
            else
                handle_button_click(app.seg_tool_base, ...
                    app.AcceptButton, 'off');
            end
            %
        end

        % Value changed function: RejectButton
        function RejectButtonValueChanged(app, event)
            %
            reject_and_exit_app(app)
            %
        end

        % Close request function: AddcellUI
        function AddcellUICloseRequest(app, event)
            %
            reject_and_exit_app(app)
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddcellUI = uifigure('Visible', 'off');
            app.AddcellUI.Name = 'MATLAB App';
            app.AddcellUI.CloseRequestFcn = createCallbackFcn(app, @AddcellUICloseRequest, true);
            app.UIGridLayout = uigridlayout(app.AddcellUI);
            app.ImagePanel = uipanel(app.UIGridLayout);
            app.ImageGridLayout = uigridlayout(app.ImagePanel);
            app.ImageAxes = uiaxes(app.ImageGridLayout);
            title(app.ImageAxes, '')
            xlabel(app.ImageAxes, '')
            ylabel(app.ImageAxes, '')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.ImageAxes.YTickLabel = {};
            app.ImageAxes.ZTick = [];
            app.ImageAxes.ZTickLabel = '';
            app.ToolsPanel = uipanel(app.UIGridLayout);
            app.ToolsPanelGridLayout = uigridlayout(app.ToolsPanel);
            app.DisplayPanel = uipanel(app.ToolsPanelGridLayout);
            app.DisplayPanelGridLayout = uigridlayout(app.DisplayPanel);
            app.DisplayLabel = uilabel(app.DisplayPanelGridLayout);
            app.DisplayLabel.Text = ' Display';
            app.MasksGridLayout = uigridlayout(app.DisplayPanelGridLayout);
            app.AButton = uibutton(app.MasksGridLayout, 'state');
            app.AButton.ValueChangedFcn = createCallbackFcn(app, @AButtonValueChanged, true);
            app.AButton.Tooltip = {'Turn on the segmentation mask for segmentation ''A''.'};
            app.AButton.Icon = 'show_a_cell_icon.png';
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.MasksGridLayout, 'state');
            app.BButton.ValueChangedFcn = createCallbackFcn(app, @BButtonValueChanged, true);
            app.BButton.Tooltip = {'Turn on the segmentation mask for segmentation ''B''.'};
            app.BButton.Icon = 'show_b_seg_icon.png';
            app.BButton.Text = 'B';
            app.FlagMaskButton = uibutton(app.MasksGridLayout, 'state');
            app.FlagMaskButton.ValueChangedFcn = createCallbackFcn(app, @FlagMaskButtonValueChanged, true);
            app.FlagMaskButton.Tooltip = {'mask over the cells labeled as ''flagged'''};
            app.FlagMaskButton.Icon = 'flag_icon.png';
            app.FlagMaskButton.Text = '';
            app.MultiNucMaskButton = uibutton(app.MasksGridLayout, 'state');
            app.MultiNucMaskButton.ValueChangedFcn = createCallbackFcn(app, @MultiNucMaskButtonValueChanged, true);
            app.MultiNucMaskButton.Tooltip = {'mask over the cells labeled as ''multi-nucleated'''};
            app.MultiNucMaskButton.Icon = 'multi_nucleated_cell_icon.png';
            app.MultiNucMaskButton.Text = '';
            app.AppliedButton = uibutton(app.MasksGridLayout, 'state');
            app.AppliedButton.ValueChangedFcn = createCallbackFcn(app, @AppliedButtonValueChanged, true);
            app.AppliedButton.Tooltip = {'Turn on the applied segmentation mask or the current working version of the segmentation mask.'};
            app.AppliedButton.Icon = 'show_applied_seg_icon.png';
            app.AppliedButton.Text = 'Applied';
            app.AppliedButton.Value = true;
            app.ImageColorsGridLayout = uigridlayout(app.DisplayPanelGridLayout);
            app.ShowMemButton = uibutton(app.ImageColorsGridLayout, 'state');
            app.ShowMemButton.ValueChangedFcn = createCallbackFcn(app, @ShowMemButtonValueChanged, true);
            app.ShowMemButton.Tooltip = {'Toggle the membrane stain on and off.'};
            app.ShowMemButton.Icon = 'show_membrane_icon.png';
            app.ShowMemButton.Text = '';
            app.MEMButton = uibutton(app.ImageColorsGridLayout, 'state');
            app.MEMButton.ValueChangedFcn = createCallbackFcn(app, @MEMButtonValueChanged, true);
            app.MEMButton.Tooltip = {'Change membrane brightness or contrast.'};
            app.MEMButton.Text = 'MEM';
            app.DAPIButton = uibutton(app.ImageColorsGridLayout, 'state');
            app.DAPIButton.ValueChangedFcn = createCallbackFcn(app, @DAPIButtonValueChanged, true);
            app.DAPIButton.Tooltip = {'Change membrane brightness or contrast.'};
            app.DAPIButton.Text = 'DAPI';
            app.BrightnessSliderLabel = uilabel(app.ImageColorsGridLayout);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.ImageColorsGridLayout);
            app.BrightnessSlider.MajorTicks = [0 25 50 75 100];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @ColorSliderValueChanged, true);
            app.BrightnessSlider.Value = 50;
            app.ContrastSliderLabel = uilabel(app.ImageColorsGridLayout);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.ImageColorsGridLayout);
            app.ContrastSlider.MajorTicks = [0 25 50 75 100];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ColorSliderValueChanged, true);
            app.ContrastSlider.Value = 50;
            app.DrawButton = uibutton(app.ToolsPanelGridLayout, 'state');
            app.DrawButton.ValueChangedFcn = createCallbackFcn(app, @DrawButtonValueChanged, true);
            app.DrawButton.Tooltip = {'Draw an annotation. Left click and hold to draw, right click to close the annotation.'};
            app.DrawButton.Icon = 'drawing_icon.png';
            app.DrawButton.Text = '';
            app.AcceptButton = uibutton(app.ToolsPanelGridLayout, 'state');
            app.AcceptButton.ValueChangedFcn = createCallbackFcn(app, @AcceptButtonValueChanged, true);
            app.AcceptButton.Tooltip = {'Exit and save work'};
            app.AcceptButton.Icon = 'done_icon.png';
            app.AcceptButton.Text = '';
            app.RejectButton = uibutton(app.ToolsPanelGridLayout, 'state');
            app.RejectButton.ValueChangedFcn = createCallbackFcn(app, @RejectButtonValueChanged, true);
            app.RejectButton.Tooltip = {'Exit and DON''T save any work.'};
            app.RejectButton.Icon = 'reject_icon.png';
            app.RejectButton.Text = '';
            app.MultiNucButton = uibutton(app.ToolsPanelGridLayout, 'state');
            app.MultiNucButton.ValueChangedFcn = createCallbackFcn(app, @MultiNucButtonValueChanged, true);
            app.MultiNucButton.Tooltip = {''};
            app.MultiNucButton.Icon = 'multi_nucleated_cell_icon.png';
            app.MultiNucButton.Text = '';
            app.FlagButton = uibutton(app.ToolsPanelGridLayout, 'state');
            app.FlagButton.ValueChangedFcn = createCallbackFcn(app, @FlagButtonValueChanged, true);
            app.FlagButton.Tooltip = {'Flag a cell to come back to or remove a flag. Click on the icon, then click the cell to flag. Repeat to remove.'};
            app.FlagButton.Icon = 'flag_icon.png';
            app.FlagButton.Text = '';
            app.TrashButton = uibutton(app.ToolsPanelGridLayout, 'state');
            app.TrashButton.ValueChangedFcn = createCallbackFcn(app, @TrashButtonValueChanged, true);
            app.TrashButton.Tooltip = {'Remove an object.'};
            app.TrashButton.Icon = 'trash_can_icon.png';
            app.TrashButton.Text = '';
            app.ObjectPlaneSpinnerLabel = uilabel(app.ToolsPanelGridLayout);
            app.ObjectPlaneSpinnerLabel.Text = 'Object Plane';
            app.ObjectPlaneSpinner = uispinner(app.ToolsPanelGridLayout);
            app.ObjectPlaneSpinner.Limits = [-5 5];
            app.ObjectPlaneSpinner.RoundFractionalValues = 'on';
            app.ObjectPlaneSpinner.ValueDisplayFormat = '%.0f';
            app.ObjectPlaneSpinner.ValueChangedFcn = createCallbackFcn(app, @ObjectPlaneSpinnerValueChanged, true);
            app.ObjectPlaneSpinner.Tooltip = {'Moves the last drawn object in front of or behind other objects. If no object has been drawn, resets to 1.'};
            app.ObjectPlaneSpinner.Value = 1;
            % Show the figure after all components are created
            app.AddcellUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Addcell(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddcellUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupAddcell(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddcellUI)
        end
    end
end