classdef ClosingDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationToolClosingDialogUIFigure  matlab.ui.Figure
        AppclosingLabel      matlab.ui.control.Label
        WouldyouliketosaveyourprogressLabel  matlab.ui.control.Label
        SaveCloseButton      matlab.ui.control.Button
        DontSaveCloseButton  matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;          
        end

        % Button pushed function: SaveCloseButton
        function SaveCloseButtonPushed(app, event)
            %
            closing_opts = 2;
            returnDialog(app.CallingApp, closing_opts)
            delete(app)
            %
        end

        % Button pushed function: DontSaveCloseButton
        function DontSaveCloseButtonPushed(app, event)
            %
            closing_opts = 1;
            returnDialog(app.CallingApp, closing_opts)
            delete(app)
            %
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %
            closing_opts = 0;
            returnDialog(app.CallingApp, closing_opts)
            delete(app)
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationToolClosingDialogUIFigure = uifigure('Visible', 'off');
            app.SegmentationToolClosingDialogUIFigure.Name = 'Segmentation Tool Closing Dialog';
            app.AppclosingLabel = uilabel(app.SegmentationToolClosingDialogUIFigure);
            app.AppclosingLabel.Text = 'App closing ...';
            app.WouldyouliketosaveyourprogressLabel = uilabel(app.SegmentationToolClosingDialogUIFigure);
            app.WouldyouliketosaveyourprogressLabel.Text = 'Would you like to save your progress?';
            app.SaveCloseButton = uibutton(app.SegmentationToolClosingDialogUIFigure, 'push');
            app.SaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCloseButtonPushed, true);
            app.SaveCloseButton.Text = ' Save & Close';
            app.DontSaveCloseButton = uibutton(app.SegmentationToolClosingDialogUIFigure, 'push');
            app.DontSaveCloseButton.ButtonPushedFcn = createCallbackFcn(app, @DontSaveCloseButtonPushed, true);
            app.DontSaveCloseButton.Text = 'Don''t Save & Close';
            app.CancelButton = uibutton(app.SegmentationToolClosingDialogUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.SegmentationToolClosingDialogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClosingDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmentationToolClosingDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationToolClosingDialogUIFigure)
        end
    end
end