classdef SegTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegToolUI                matlab.ui.Figure
        ALabel                   matlab.ui.control.Label
        BLabel                   matlab.ui.control.Label
        Selections_Panel         matlab.ui.container.Panel
        JumpCellButton           matlab.ui.control.NumericEditField
        Classification_Panel     matlab.ui.container.Panel
        ClassificationLabel      matlab.ui.control.Label
        PanelLine                matlab.ui.control.Label
        DrawonDAPIButton         matlab.ui.control.StateButton
        DrawonMemButton          matlab.ui.control.StateButton
        ArtifactButton           matlab.ui.control.StateButton
        NeitherButton            matlab.ui.control.StateButton
        AButton                  matlab.ui.control.StateButton
        BButton                  matlab.ui.control.StateButton
        ABButton                 matlab.ui.control.StateButton
        MultiNucButton           matlab.ui.control.StateButton
        FlagButton               matlab.ui.control.StateButton
        AddcellButton            matlab.ui.control.StateButton
        TrashButton              matlab.ui.control.StateButton
        ObjectPlaneSpinnerLabel  matlab.ui.control.Label
        ObjectPlaneSpinner       matlab.ui.control.Spinner
        Display_Panel            matlab.ui.container.Panel
        ContrastSliderLabel      matlab.ui.control.Label
        ToggleSegButton          matlab.ui.control.StateButton
        ContrastSlider           matlab.ui.control.Slider
        BrightnessSliderLabel    matlab.ui.control.Label
        BrightnessSlider         matlab.ui.control.Slider
        DisplayLabel             matlab.ui.control.Label
        AppliedSegButton         matlab.ui.control.StateButton
        DAPIButton               matlab.ui.control.StateButton
        MemButton                matlab.ui.control.StateButton
        SaveTableButton          matlab.ui.control.StateButton
        LoadImageButton          matlab.ui.control.StateButton
        NextCellButton           matlab.ui.control.StateButton
        forwardOnecellButton     matlab.ui.control.StateButton
        backOnecellButton        matlab.ui.control.StateButton
        JumptocellButton         matlab.ui.control.StateButton
        Images_Panel             matlab.ui.container.Panel
    end

    %
    properties (Access = public)
       %% -----------------------------------------------
        % for image display
        % format
        A1_Image; % the dapi machine learning image
        A2_Image; % the membrane and dapi machine learning image
        B1_Image; % the dapi inform image
        B2_Image; % the membrane and dapi inform image
        rr; % the right side index of the image for a given cell cut out
        ll; % the left side index of the image for a given cell cut out
        bb; % the bottom side index of the image for a given cell cut out
        tt; % the top side index of the image for a given cell cut out
        seg_IF; % segmentation overlay image for IF
        seg_SP; % segmentation overlay image for SP
        IF_c; % current cell coordinates for IF
        SP_c; % current cell coordinates for SP
        im_d; % dapi image
        im_m; % membrane image
        A; % image segmentation to apply for A side (1 IF; 2 SP)
        B; % image segmenation to apply for B side (1 IF; 2 SP)
        applied_seg_im; % applied segmentation mask, for cells already 
        % reviewed
        paired_cells
        x_centroids
        y_centroids
        im_SP_seg_brds; % superpixel orginal segmentation mask
        im_IF_seg_brds; % inform original segmentation mask
        %% -------------------------------------------------
        % for UI functionalities
        overlap_table; % final\ resulting table with overlaps and other
        % relevant info
        draw_im; % the image with the drawn objects
        closing_opts % 1 to close the UI, 0 to leave is open
        addcell_instr = 0;
        %% -----------------------------------------------
        % variables read from input images
        im_SP; % Machine learning label matrix
        im_IF; % inForm label matrix
        nRows; % rows
        nCols; % columns
        fname; % current image name
        mem_dapi_im; % dapi and membrane component layers in column vector
        c_ground_level = 1
        max_count % max count value for progress tracking
    end
    %
    properties (Access = private)
        %% -----------------------------------------------
        % variables read from input images
        wd; % directory with the inform and SP_data subfolders in it
        IF_filename; % the inform file name
        SP_filename; % superpixel file name
        %% -----------------------------------------------
        % variables for overlap table computation
        IF_cells; % cell array of cells in inForm label matrix
        SP_cells; % cell array of cells in SP label matrix
        tmp_mid; % temporary centroid or midpoint variable passed between
        % functions, created from label matrix
        IF_table; % intial table with all inForm cells in it
        SP_table; %intial table with all machine learning cells in it
        cells_n; % cells variable passed between functions
        q; % temporary table used throughout computation as the overlap 
        % table
        o_tbl_IF; % inform overlap table
        o_tbl_SP; % machine learning overlap table
        %% -----------------------------------------------
        % for image display
        % format
        mycols = [0 0 1; % DAPI
            1 1 1];   % Membrane
        a_m = 2.5; % default values for the colors
        a_d = 2.5;
        b_m = 200;
        b_d = 200;
        seg_col = 225; % segmentation color intensity for all boundaries
        %% -------------------------------------------------
        % for UI functionalities
        e; % error variable passed between functions; 0 for no error
        pgbar; % progress bar passed between apps for the image loading
        hh2; % handle passed between function for the closing dialog
        n; % current cell count number in the overlap table for analysis
        cell_idx; % cell index or row number for the current cell
        cell_obj; % the cell object created when drawing a given cell
        brd_ii; % linear indicies of the current cell
        n_frac_pct_allowed = .2 %fraction of check_cell == 2 allowed to 
        % be differing
        n_diffs_allowed;
        ClosingDialogApp % closing app dialog
        AddcellApp % add cell app
        fullimageViewApp
        %
    end
    %
    methods (Access = private)
        %
        function updateimage(app,imagefile)
            %
            parse_image_filename(app, imagefile)
            %
            switch app.e
                case  1
                    uialert(app.SegToolUI,['Image not loaded: ',....
                        'IF path: ', app.IF_filename,' does not exist.',...
                        'Check file structure.'],...
                         "Image Path Error");
                    return
                case 2
                    uialert(app.SegToolUI,['Image not loaded: ',....
                        'SP path: ', app.SP_filename,' does not exist.',...
                        'Check file structure.'],...
                         "Image Path Error");
                    return
                case 3
                    uialert(app.SegToolUI,['Image not loaded: ',....
                        'input path must contain either',...
                        '"superpixel" or "inform_data".'],...
                         "Image Path Error");
                    return
                case 4
                    uialert(app.SegToolUI,['Image not loaded: ',....
                        'input path must contain either',...
                        '"superpixel" or "inform_data" type data.'],...
                         "Image Path Error");
                    return
            end  
            %
            readimages(app)
            %
            switch app.e
                case 1
                    uialert(app.SegToolUI,...
                        'inForm Image Error: MISSING IMAGE LAYERS.',...
                        "Loading Image Error");
                    return 
                case 2
                    uialert(app.SegToolUI,...
                        ['inForm Image Error: check path',...
                        app.IF_filename,'.'], "Loading Image Error");
                    return
                case 3
                    uialert(app.SegToolUI,...
                        ['Superpixel Image Error: check path',...
                        app.SP_filename,'.'], "Loading Image Error");
                    return
            end
            % 
            % read in or create the table
            %
            app.pgbar.Value = .60;
            app.pgbar.Message = 'Checking for previous comparison files.';
            pause(.5)
            comp_p_file_IF = [app.wd,'\inform_data\Component_Tiffs\',...
                app.fname,'_comparison_seg_data.csv'];
            %
            if exist(comp_p_file_IF,'file')
                %reload_overlap_table(app)
                try
                    reload_overlap_table(app)
                catch
                    uialert(app.SegToolUI,...
                        'Unknown error in loading table: please try again',...
                       'Computing Overlaps Error');
                    return
                end
            else
                try
                    compute_pairs(app)
                catch
                    uialert(app.SegToolUI,...
                        'Unknown error in computing overlaps: please try again',...
                        'Computing Overlaps Error');
                    return
                end
            end
            %
            % compute n differences allowed for cell_check = 2
            %
            ii = app.overlap_table.cell_check == 2;
            app.n_diffs_allowed = ...
                app.n_frac_pct_allowed * length(find(ii));
            %
            % check that the allowed value has not yet been met
            %
            %app.max_cell_value = max(app.overlap_table.pairid);
            x = app.overlap_table;
            ii = x.class_selection == 4;
            %
            if any(ii)
                measure_jo_cells(app);
            else
                app.n_diffs_allowed = -1;
            end
            %
            % remove any cells that are set to review but not in either IF
            % or SP segmentations as these are errors
            %
            ii = cellfun(@isempty, app.overlap_table.SP_cells) & ...
                cellfun(@isempty, app.overlap_table.IF_cells) & ...
                app.overlap_table.class_selection == 0;
            if any(ii)
                app.overlap_table.class_selection(ii) = -1;
            end
            %
            % find correct cell
            %
            app.cell_idx = find(app.overlap_table.class_selection == 0, 1);
            apply_image_colors(app, 1, 1)
            %
            rstAllButtons(app, 1)
            if isempty(app.cell_idx)
                %
                final_obj(app, 2)
                return
                %
            end
            %
            app.n = app.overlap_table.counter(app.cell_idx);
            getSegmap(app)
            compile_applied_seg_map(app)
            %
            if app.e
                 uialert(app.SegToolUI,...
                        ['Unknown error in building seg mask:',...
                         'please try again. Error code: ',num2str(app.e)],...
                        'Computing Segmentation Mask Error');
                    return
            end
            %
            disp_images(app, 1, app.n)            
            %
        end
        %
        function parse_image_filename(app, imagefile)
            %%
            % this function parses the input image filename to get both the
            % superpixel and the inform data images
            %
            %%
            fn = extractBetween(...
                imagefile,'Component_Tiffs\','_component');
            if isempty(fn)
                app.e = 4;
                return
            end
            app.fname = fn{1};
            app.e = 0;
            %
            % if input name is in superpixel tree
            %
            if contains(imagefile, 'superpixel')
                app.wd = extractBefore(...
                    imagefile, ['\','superpixel','\Component_Tiffs']);
                app.SP_filename = imagefile;
                imagefile2 = replace(imagefile,...
                    'superpixel', 'inform_data');
                app.IF_filename = replace(imagefile2,'_seg','_w_seg');
                if ~isfile(app.IF_filename)
                    app.e = 1;
                    return
                end
                %
                % if input name is in the inform tree
                %
            elseif contains(imagefile, 'inform_data')
                app.wd = extractBefore(...
                    imagefile, ['\','inform_data','\Component_Tiffs']);
                app.IF_filename = imagefile;
                imagefile2 = replace(imagefile,...
                    'inform_data','superpixel');
                app.SP_filename = replace(imagefile2,'_w_seg','_seg');
                if ~isfile(app.SP_filename)
                    app.e = 2;
                    return
                end
                %
                % if input name is not in the proper structure
                %
            else
                app.e = 3;
                return
            end
            %
        end
        %
        function readimages(app)
            %%
            % this function reads in the images and gather necessary
            % metadata along the way
            %
            %%
            app.pgbar.Value = .15;
            app.pgbar.Message = 'Getting image file metadata';
            pause(.5)
            %
            % get the inform image number of layers and dims
            %
            app.e = 0;
            try
                im_info = imfinfo(app.IF_filename);
            catch
                app.e = 2;
            end
            %
            layers = length(im_info);
            app.nRows = im_info(1).Height;
            app.nCols = im_info(1).Width;
            %
            if layers ~= 15
                app.e = 1;
                return;
            end
            %
            app.pgbar.Value = .20;
            app.pgbar.Message = 'Reading InForm image layers into memory.';
            pause(.5)
            %
            % read INFORM IMAGE
            %
            try
                ic = 0;
                app.im_IF = zeros(app.nRows, app.nCols, 5);
                for i1 = 11:15
                    ic = ic + 1;
                    app.im_IF(:,:,ic) = imread(app.IF_filename,i1);
                end
                %
                ic = 0;
                app.mem_dapi_im = zeros(app.nRows * app.nCols, 2);
                for i1 = [1, 9]
                    im = imread(app.IF_filename, i1);
                    im = im ./ max(im,[],'all');
                    ic = ic + 1;
                    app.mem_dapi_im(:,ic) = reshape(im, [], 1);
                end
                %
            catch
                app.e = 2;
            end
            %
            % read the superpixel image; we denote this as SP in the code
            % for 'machine learning'
            %
            app.pgbar.Value = .40;
            app.pgbar.Message =...
                'Reading superpixel image layers into memory.';
            pause(.5)
            %
            try
                app.im_SP = zeros(app.nRows, app.nCols, 2);
                for i1 = 1:2
                    app.im_SP(:,:,i1) = imread(app.SP_filename, i1);
                end
            catch
                app.e = 3;
            end
            %
            % create inform segmenation boundary image
            %
            app.im_IF_seg_brds = zeros(app.nRows, app.nCols, 1);
            %
            for i1 = 2:3
                brd = (imdilate(app.im_IF(:,:,i1), ...
                    ones(3,3)) - app.im_IF(:,:,i1)) > 0;
                app.im_IF_seg_brds(brd) = app.seg_col;
            end
            %
            % create superpixel segmenation boundary image
            %
            app.im_SP_seg_brds = zeros(app.nRows, app.nCols, 1);
            %
            for i1 = 1:2
                brd = (imdilate(app.im_SP(:,:,i1), ...
                    ones(3,3)) - app.im_SP(:,:,i1)) > 0;
                app.im_SP_seg_brds(brd) = app.seg_col;
            end
            %
        end
        %
        function compute_pairs(app)
            %%
            % this is where the tables are computed for cells and cell 
            % overlap pct is assigned
            %%
            app.pgbar.Value = .61;
            app.pgbar.Message = ['Previous comparison files not ',...
                'found ... computing new comparison results'];
            pause(1)
            %
            app.pgbar.Value = .62;
            app.pgbar.Message = ['Computing new comparison results ...',...
                ' finding cell objects'];
            pause(.5)
            %
            % SP cell table
            %
            im = app.im_SP(:,:,1);
            app.cells_n = label2idx(im)';
            m1 = regionprops(im,'centroid');
            app.tmp_mid = struct2cell(m1);
            crt_int_tbl(app, 'SP');
            %
            % get distinct objects in inform
            %
            getIFdistinct(app);
            %
            % create the IF table
            %
            crt_int_tbl(app, 'IF');
            %
            % get the cells which pixels overlap with SP
            %
            app.pgbar.Value = .70;
            app.pgbar.Message = ['Computing new comparison results ...',...
                ' computing overlaps'];
            pause(.5)
            %
            comp_overlap(app);
            %
            % add non overlapping cells
            %
            app.pgbar.Value = .80;
            app.pgbar.Message = ['Computing new comparison results ...',...
                ' finding non overlapping cells'];
            pause(.5)
            %
            add_no(app);
            %
            % remove edge cells, get linear indicies of all boarder pixels
            %
            app.pgbar.Value = .80;
            app.pgbar.Message = ['Computing new comparison results ...',...
                ' cleaning up and saving intial table'];
            pause(.5)
            %
            rm_edge_cells(app, 1);
            rm_edge_cells(app, 2)
            %
            % intialize the final results columns and for 80% or above 
            % overlap save SP algorithm segmentation
            %
            int_results_cols(app);
            %
            % save results
            %
            x = app.q;
            %
            x.counter = zeros(height(x),1);
            ii = x.cell_check > 0;
            x.counter(ii) = (1:length(find(ii)))';
            %
            app.overlap_table = x;
            saveLabel(app)
            saveTable(app)
            %
        end
        %
        function crt_int_tbl(app, tt)
            %%
            % intialize the table for the overlap computation
            %%
            cells_n1 = app.cells_n;
            m1 = app.tmp_mid;
            m1 = cell2mat(m1);
            m_o = m1(1:2:end);
            m_e = m1(2:2:end);
            %
            tbl = table();
            tbl.([tt,'_cellid']) = (1:length(cells_n1))';
            tbl.([tt,'_cells']) = cells_n1;
            tbl.([tt,'_X_centroid']) = m_o';
            tbl.([tt,'_Y_centroid']) = m_e';
            tbl.([tt,'_n_pixels']) = cellfun('length',cells_n1);
            %
            if strcmp(tt, 'SP')
                app.SP_table = tbl;
            elseif strcmp(tt,'IF')
                app.IF_table = tbl;
            end
            %
        end
        %
        function getIFdistinct(app)
            %%
            % get the distinct cell objects from both inform cell
            %  segmentation layers
            %%
            %
            % get distinct objects in inform
            %
            im = app.im_IF(:,:,2);
            IF_cells_1 = label2idx(im);
            m1 = regionprops(im,'centroid');
            m1 = struct2cell(m1);
            %
            im = app.im_IF(:,:,3);
            IF_cells_2 = label2idx(im);
            m2 = regionprops(im,'centroid');
            m2 = struct2cell(m2);
            %
            if length(IF_cells_1) > length(IF_cells_2)
                nn = length(IF_cells_1);
            else
                nn = length(IF_cells_2);
            end
            %
            IF_cellso = cell(nn, 1);
            mo = cell(1,nn);
            %
            ii = ~cellfun(@isempty,IF_cells_1);
            IF_cellso(ii) = IF_cells_1(ii);
            mo(ii) = m1(ii);
            %
            ii = ~cellfun(@isempty,IF_cells_2);
            IF_cellso(ii) = IF_cells_2(ii);
            mo(ii) = m2(ii);
            %
            app.cells_n = IF_cellso;
            app.tmp_mid = mo;
            %
        end
        %
        function comp_overlap(app)
            %%
            % This function computes the overlaps and adds them to the appropriate rows
            % in the table
            %
            %%
            o = [];
            for level = 1:2
                im1 = app.im_IF(:,:,level+1);
                overlap = im1 > 0 & app.im_SP > 0;
                pairs = [im1(overlap), app.im_SP(overlap)];
                [ii, ~, kk] = unique(pairs, 'rows');
                pairs_cnt = [ii,accumarray(kk,1)];
                %
                idx1 = pairs_cnt(:,1);
                idx2 = pairs_cnt(:,2);
                %
                crt_ovr_tbl(app,idx1,...
                    pairs_cnt(:,2) ,pairs_cnt(:,3), 'IF');
                %
                crt_ovr_tbl(app,idx2,...
                    pairs_cnt(:,1) ,pairs_cnt(:,3), 'SP');
                %
                tbl = outerjoin(app.o_tbl_IF,app.o_tbl_SP,'MergeKeys',1);
                %
                tbl.IF_level = repmat(level, height(tbl),1);
                o = [o;tbl]; %#ok<AGROW> 
            end
            %
            o.joint_overlap = o.IF_frac .* o.SP_frac;
            %
            % find cells with jo < 10% and remove them from the table
            % these cells do not actually overlap well and are probably
            % just beside one another
            %
            ii = o.joint_overlap < .1;
            o(ii,:) = [];
            %
            app.q = o;
            %
        end
        %
        function crt_ovr_tbl(app,idx,pairs,cnt,tt)
            %%
            % define the table with the overlaps in it
            %
            %%
            if strcmp(tt, 'SP')
                tbl_in = app.SP_table;
            elseif strcmp(tt, 'IF')
                tbl_in = app.IF_table;
            end
            %
            tbl = tbl_in(idx,:);
            tbl.([tt,'_overlap']) =  cnt;
            tbl.([tt,'_frac']) = double(tbl.([tt,'_overlap']))...
                ./ tbl.([tt,'_n_pixels']);
            tbl.([tt,'_paired_w']) = pairs;
            tbl.pairid = (1:height(tbl))';
            %
            if strcmp(tt, 'SP')
                app.o_tbl_SP = tbl;
            elseif strcmp(tt, 'IF')
                app.o_tbl_IF = tbl;
            end
            %
        end
        %
        function add_no(app)
            %
            tbl = app.q;
            colnames = tbl.Properties.VariableNames;
            %
            z = height(tbl);
            %
            app.IF_table.IF_level = ones(height(app.IF_table), 1);
            im1 = app.im_IF(:,:,3);
            im1 = unique(im1);
            ii = ismember(app.IF_table.IF_cellid, im1);
            app.IF_table.IF_level(ii) = repmat(2, sum(ii), 1);
            %
            IF_tableo = app.IF_table;
            idx1 = unique(IF_tableo.IF_cellid);
            idx2 = unique(tbl.IF_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, IF_tableo, colnames, z);
            %
            z = height(app.q);
            app.SP_table.IF_level = ones(height(app.SP_table), 1);
            SP_tableo = app.SP_table;
            idx1 = unique(SP_tableo.SP_cellid);
            idx2 = unique(tbl.SP_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, SP_tableo, colnames, z);
            %
        end
        %
        function no_o_tbl(app, idx, tbl_in, colnames, z)
            %%
            % create the table of the size of the overlap table for cells
            % without overlaps
            %%
            no_tbl = tbl_in;
            no_tbl(idx,:) = [];
            no_tbl.pairid = (z + 1:z + height(no_tbl))';
            colnames_no = no_tbl.Properties.VariableNames;
            ii = ~ismember(colnames, colnames_no);
            tbl = array2table(zeros(height(no_tbl),sum(ii)));
            tbl = [no_tbl, tbl];
            tbl.Properties.VariableNames = [colnames_no,colnames(ii)];
            %
            titles = {'IF','SP'};
            for i1 = 1:2
                if ~ismember([titles{i1},'_cells'], colnames_no)
                    tbl.([titles{i1},'_cells']) = num2cell(...
                        tbl.([titles{i1},'_cells']));
                end
            end
            %
            app.q = [app.q; tbl];
            %
        end
        %
        function rm_edge_cells(app, opt)
            %%
            % this function removes the cells that touch the edges of the 
            % images from the training set
            %%
            x = app.q;
            %
            % build an empty image and get the border pixels
            %
            im_e = zeros(app.nRows, app.nCols);
            im_e(1,1:app.nCols) = ones(1, app.nCols);
            im_e(app.nRows,1:app.nCols) = ones(1, app.nCols);
            im_e(1:app.nRows, 1) = ones(1, app.nRows);
            im_e(1:app.nRows, app.nCols) = ones(1, app.nRows);
            ii = find(im_e);
            %
            % find where either the machine learning or the inform 
            % algorithm defines cells with any pixels touching the edge of 
            % the image
            %
            if opt == 1
                SP_edges_p = cellfun(@(y) sum(ismember(y, ii)), x.SP_cells);
                IF_edges_p = cellfun(@(y) sum(ismember(y, ii)), x.IF_cells);
                edges_p = SP_edges_p + IF_edges_p;
                %
                % remove those rows from the table
                %
                idx = edges_p > 0;
                x(idx,:) = [];
                app.q = x;
                %
            else
                %
                im = app.im_SP(:,:,1);
                SP_cells_1 = label2idx(im);
                SP_edges_p = cellfun(...
                    @(y) sum(ismember(y, ii)), SP_cells_1);
                %
                im = app.im_IF(:,:,2);
                IF_cells_1 = label2idx(im);
                IF_edges_p_1 = cellfun(...
                    @(y) sum(ismember(y, ii)), IF_cells_1);
                %
                im = app.im_IF(:,:,3);
                IF_cells_2 = label2idx(im);
                IF_edges_p_2 = cellfun(...
                    @(y) sum(ismember(y, ii)), IF_cells_2);
                %
                idx = find(ismember(app.im_SP(:,:,1), find(SP_edges_p)) | ...
                    ismember(app.im_IF(:,:,2), find(IF_edges_p_1)) | ...
                    ismember(app.im_IF(:,:,3), find(IF_edges_p_2)));
                %
                % remove those pixels from the im_mem_dapi and segmentation
                % images 
                %
                app.mem_dapi_im(idx) = 0;
                app.im_SP(idx) = 0;
                app.im_IF(idx + 2 * app.nCols * app.nRows) = 0;
                app.im_IF(idx + 3 * app.nCols * app.nRows) = 0;
            %
            end
            %
        end
        %
        function int_results_cols(app)
            %%
            % this function intializes the results columns and defines 
            % the cells that overlap 80% above as the machine learning 
            % algorithm cell but defines the cell include parameter as 
            % 4 instead of 2
            %
            %%
            x = app.q;
            %
            % remove empty cells
            %
            ii = isnan(x.SP_X_centroid);
            x(ii,:) = [];
            ii = isnan(x.IF_X_centroid);
            x(ii,:) = [];
            %
            % redefine the cellid
            %
            x.pairid = (1:height(x))';
            %
            % results columns
            %
            x.class_selection = zeros(height(x),1);
            x.cell_check = zeros(height(x),1);
            x.flags = zeros(height(x),1);
            %
            % settings for the 80% overlap cells; look over 20% of these
            %
            ii = x.joint_overlap >= .8;
            x.cell_check(~ii) = 1; % look over all of these
            x.class_selection(ii) = 4; % do not look over 
            %
            %  randomly sample in 80% not to look over 
            %
            ii = find(ii);
            n_overlaps = length(ii);
            r2 = (1:n_overlaps)';
            r = randsample(r2, floor(.8 * n_overlaps));
            %
            % store the cell objects, these are in a row index vector
            % according to the pairid
            %
            app.paired_cells = cell(height(x), 1);
            app.x_centroids = zeros(height(x), 1);
            app.y_centroids = zeros(height(x), 1);
            app.paired_cells(ii(r)) = x.SP_cells(ii(r));
            app.x_centroids(ii(r)) = x.SP_X_centroid(ii(r));
            app.y_centroids(ii(r)) = x.SP_Y_centroid(ii(r));
            %
            r3 = ~ismember(r2, r);
            x.cell_check(ii(r3)) = 2;
            x.class_selection(ii(r3)) = 0;
            %
            x.ground_level = ones(height(x), 1);
            %
            x = x(:,{'IF_cellid','IF_X_centroid','IF_Y_centroid',...
                'IF_paired_w','SP_cellid','SP_X_centroid',...
                'SP_Y_centroid','SP_paired_w','pairid','IF_level',...
                'joint_overlap','class_selection','cell_check',...
                'flags','ground_level','IF_cells','SP_cells'});
            x.Properties.VariableNames = {'IF_cellid','IF_X_centroid',...
                'IF_Y_centroid','SP_paired_w_IF','SP_cellid',...
                'SP_X_centroid','SP_Y_centroid','IF_paired_w_SP',...
                'pairid','IF_level','joint_overlap','class_selection',...
                'cell_check','flags','ground_level','IF_cells','SP_cells'};
            app.q = x;
            %
            app.draw_im = zeros(app.nRows, app.nCols, 'uint16');
            %
        end
        %
        function getSegmap(app)
            %%
            % The function defines the image cut out region and builds the
            % orginal image masks for SP and IF. Does not rely on the
            % applied segmentation
            %%
            app.e = 0;
            inp = app.cell_idx; % row index
            x = app.overlap_table;
            %
            % determine cell of interest for both types
            %
            try
                IF_cell = x.IF_cells{inp};
                %
                SP_cell = x.SP_cells{inp};
            catch
                app.e = 1;
            end
            %
            % determine which cell object to center on, if both values are
            % length less than 2 the cell is an added cell and find it's
            % centroid
            %
            try
                IF_n_pixels = length(IF_cell);
                SP_n_pixels = length(SP_cell);
                if IF_n_pixels > SP_n_pixels
                    x_center = x.IF_X_centroid(inp);
                    y_center = x.IF_Y_centroid(inp);
                elseif IF_n_pixels < 2 && SP_n_pixels < 2 && ...
                        x.class_selection(inp) == 3
                    try
                        ii = app.draw_im == x.pairid(inp);
                        m1 = regionprops(ii,'centroid');
                        while isempty(m1)
                            app.cell_idx = app.cell_idx - 1;
                            inp = app.cell_idx;
                            ii = app.draw_im == x.pairid(inp);
                            m1 = regionprops(ii,'centroid');
                        end
                        x_center = m1.Centroid(1);
                        y_center = m1.Centroid(2);
                    catch
                    end
                else
                    x_center = x.SP_X_centroid(inp);
                    y_center = x.SP_Y_centroid(inp);
                end
                %
                % get the edges to be shown
                %
                app.ll = floor(x_center - 50);
                app.rr = floor(x_center + 50);
                app.tt = floor(y_center - 50);
                app.bb = floor(y_center + 50);
                %
                if app.tt < 1
                    app.tt = 1;
                end
                if app.bb > app.nRows
                    app.bb = app.nRows;
                end
                if app.ll < 1
                    app.ll = 1;
                end
                if app.rr > app.nCols
                    app.rr = app.nCols;
                end
            catch
                app.e = 2;
            end
            %
            % rebuild the original masks
            %
            try
                %
                im_s = app.im_IF_seg_brds(:,:);
                app.seg_IF = buildSegMask(app, im_s, IF_cell);
                %
                im_s = app.im_SP_seg_brds(:,:);
                app.seg_SP = buildSegMask(app, im_s, SP_cell);
                %
            catch
                app.e = 3;
            end
            %
            
        end
        %
        function im_s = buildSegMask(app, im_s, cell_locs)
            %%
            % cut out only the desired region from the image and translate
            % the coordinates to the 100x100 px view
            %%
            im_s = im_s(app.tt(1):app.bb(1),app.ll(1):app.rr(1),1);
            s1 = size(im_s);
            im_s = cat(3,im_s, zeros(s1(1),s1(2),2));
            if ~isempty(cell_locs) && ~sum(cell_locs == 0)
                [r, c] = ind2sub([app.nRows, app.nCols], cell_locs);
                r = r - app.tt + 1;
                c = c - app.ll + 1;
                loc = sub2ind(s1(1:2), r, c);
                im_s(loc + s1(1)*s1(2)) = 255;
            end
            %
        end
        %
        function saveProtocol(app)
            %
            if ~isempty(app.overlap_table)
                %
                app.e = 0;
                try
                    hh1 = findall([app.SegToolUI], '-property', 'Enable');
                    set(hh1,'Enable', 'off')
                    %
                    d = uiprogressdlg(app.SegToolUI,'Title','Please Wait',...
                        'Message',...
                        'Saving current comparison image and table');
                catch
                    uialert(app.SegToolUI,['Comparision ',....
                        'image not saved! Please try again.'],...
                         "Saving Result Error");
                    d.Value = 1;
                    pause(.5)
                    close(d);
                    %
                    set(hh1,'Enable', 'on')
                    return
                end
                %
                saveLabel(app)
                saveTable(app)
                %
                switch app.e
                    case 1
                        uialert(app.SegToolUI,['Comparision ',....
                            'image not saved! Check that you have',...
                            ' access to the output location in: ',...
                            app.wd], "Saving Result Error");
                    case 2
                        uialert(app.SegToolUI,['Comparision ',....
                            'csv file not saved! Check that you have',...
                            ' access to the output location in: ',...
                            app.wd],"Saving Result Error");
                end
                %
                d.Value = 1;
                pause(.5)
                close(d);
                %
                set(hh1,'Enable', 'on')
                %
            end
            %
        end
        %
        function saveLabel(app)
            %%
            % save the drawn segmentation image
            %
            %%
            %
            x = app.overlap_table;
            ii = x.class_selection == 3;
            %
            idx = x.pairid(ii);
            gl = x.ground_level(ii);
            %
            mlow = min(gl);
            mhigh = max(gl);
            im = zeros(app.nRows, app.nCols, 1);
            %
            for m_count = mlow:mhigh
                ii = gl == m_count;
                cells_n1 = app.paired_cells(idx(ii));
                im1 = createLabelmatrix(app, cells_n1, idx(ii));
                im(im1(:,:,1) > 0) = im1(im1(:,:,1) > 0);
                %
            end
            %
            % set image output properties
            %
            ds.Photometric = Tiff.Photometric.MinIsBlack;
            ds.BitsPerSample   = 16;
            ds.SamplesPerPixel = 1;
            ds.SampleFormat = Tiff.SampleFormat.UInt;
            ds.RowsPerStrip = 41;
            ds.MaxSampleValue = 256;
            ds.MinSampleValue = 0;
            ds.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            ds.Software = 'MATLAB';
            ds.ResolutionUnit = Tiff.ResolutionUnit.Inch;
            ds.XResolution = 300;
            ds.YResolution = 300;
            ds.Compression = Tiff.Compression.LZW;
            ds.ImageLength = app.nRows;
            ds.ImageWidth = app.nCols;
            %
            try
                oiname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                    '_comparison_seg_data.tif'];
                if exist(oiname,'file')
                    delete(oiname)
                end
            catch
                app.e = 1;
                return
            end
            %
            d = datenum(datetime);
            f = dir(oiname);
            if isempty(f)
                f(1).datenum = d - 100;
            end
            ic = 0;
            %
            while d > f.datenum
                %
                % write image
                %
                try
                    ii = Tiff(oiname,'w');
                    %
                    ii.setTag(ds)
                    d = uint16(im(:,:,1));
                    write(ii,d);
                    close(ii)
                    %
                catch
                    app.e = 1;
                    break
                end
                %
                f = dir(oiname);
                if isempty(f)
                    f(1).datenum = d - 100;
                end
                %
                ic = ic + 1;
                if ic == 5
                    app.e = 1;
                    break
                end
                %
            end
        end
        %
        function L = createLabelmatrix(app, cells_n1, ids)
            %%
            % create a label matrix L from the cells in cells_n
            %
            %%
            %
            L = zeros(app.nRows,...
                app.nCols, 'uint16');
            %
            for i1 = 1:length(cells_n1)
                idx = cells_n1{i1};
                if length(idx) == 1
                    continue
                end
                %
                L(idx) = ids(i1);
            end
            %
        end
        %
        function saveTable(app)
            %%
            % This function saves the app.overlap_table as a .csv file 
            % without the cells in it
            %%
            x = app.overlap_table;
            %
            x.IF_cells = [];
            x.SP_cells = [];
            x.counter = [];
            %
            ofname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.csv'];
            %
            d = datenum(datetime);
            f = dir(ofname);
            if isempty(f)
                f(1).datenum = d - 100;
            end
            %
            ic = 0;
            while d > f.datenum
                try
                    writetable(x, ofname)
                catch
                end
                %
                pause(.5)
                f = dir(ofname);
                if isempty(f)
                    f(1).datenum = d - 100;
                end
                ic = ic + 1;
                if ic == 5
                    app.e = 2;
                    break
                end
            end
            %
        end
        %
        function reload_overlap_table(app)
            %%
            % this function reads in the comparison seg data and label 
            % matrix then rearranges to the overlap_table
            %%
            app.pgbar.Value = .61;
            app.pgbar.Message = ['Previous comparison files found ',...
                '... loading into memory'];
            pause(.5)
            %
            % read the old table
            %
            ofname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.csv'];
            x = readtable(ofname);
            %
            % convert old table format to new
            %
            if width(x) == 14
               x.ground_level = ones(height(x),1);
            elseif width(x) ~= 15
               app.q = x;
               convertOldTableformat(app)
               app.q.ground_level = ones(height(x),1);
               x = app.q;
            end
            %
            ii = x.pairid == 0;
            x(ii, :) = [];
            %
            % get SP cell objects
            %
            app.cells_n = label2idx(app.im_SP)';
            m1 = regionprops(app.im_SP(:,:,1),'centroid');
            app.tmp_mid = struct2cell(m1);
            crt_int_tbl(app, 'SP');
            app.SP_table = app.SP_table(:,{'SP_cellid','SP_cells'});
            x = outerjoin(x, app.SP_table,...
                'Type','left','Keys',{'SP_cellid'},'MergeKeys',1);
            %
            % get IF cell objects
            %
            getIFdistinct(app);
            crt_int_tbl(app, 'IF');
            app.IF_table = app.IF_table(:,{'IF_cellid','IF_cells'});
            x = outerjoin(x, app.IF_table,...
                'Type','left','Keys',{'IF_cellid'},'MergeKeys',1);
            x = sortrows(x,'pairid','ascend');
            %
            % remove edge cells from the displays
            %
            app.q = x;
            rm_edge_cells(app, 2)
            %
            % add cell counter
            %
            x.counter = zeros(height(x),1);
            %
            ii1 = x.class_selection ~= 0 & x.class_selection ~= 4 ...
                & x.cell_check ~= 0;
            x.counter(ii1) = (1:sum(ii1))';
            %
            ii = x.class_selection == 0;
            x.counter(ii) = (sum(ii1) + 1: sum(ii1) + sum(ii))';
            %
            app.overlap_table = x;
            %
            read_comp_im(app)
            %            
        end
        %
        function read_comp_im(app)
            %%
            % this function reads in the image with the drawn cells to memory
            %%
            oiname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.tif'];
            %
            % read in drawn cells
            %
            try
                image_info = imfinfo(oiname);
            catch
                uialert(app.SegToolUI,...
                    'Error comparison image not found',...
                    'Error')
                return
            end
            %
            switch length(image_info)
                case 6
                    %
                    % convert old version to new version
                    %
                    app.draw_im = zeros(app.nRows, app.nCols);
                    x = app.overlap_table;
                    for level = 1:2
                        ii = x.IF_level == level & x.class_selection == 3;
                        L = imread(oiname,level + 4);
                        cells_n1 = x.pairid(ii);
                        c = reshape(L,1,[]);
                        ii = c > 0;
                        app.draw_im(ii) = cells_n1(c(ii));
                        %
                    end
                    %
                case 2
                    %
                    app.draw_im = imread(oiname, 1);
                    %
                case 1
                    %
                    app.draw_im = imread(oiname, 1);
                    %
            end
            %
            % get the cell objects from the drawn image
            %
            d_cells_n1 = label2idx(app.draw_im);
            rp = regionprops(app.draw_im,'Centroid');
            rp = struct2cell(rp);
            rp = cell2mat(rp);
            %
            % get the logical index in the table and the pairid of the cell
            % of interest (pairid is the value in the label matrix)
            %
            ii = app.overlap_table.class_selection == 3;
            idx = app.overlap_table.pairid(ii);
            %
            ii1 = idx > length(d_cells_n1);
            if any(ii1)
                ii2 = ismember(app.overlap_table.pairid,idx(ii1));
                app.overlap_table.class_selection(ii2) = -1;
                ii = app.overlap_table.class_selection == 3;
                idx = app.overlap_table.pairid(ii);
            end
            %
            % pre-allocate final object variables
            %
            app.paired_cells = cell(max(app.overlap_table.pairid), 1);
            app.x_centroids = zeros(max(app.overlap_table.pairid), 1);
            app.y_centroids = zeros(max(app.overlap_table.pairid), 1);
            %
            % insert the proper values from the drawn cells into the final
            % object variables
            %
            cells_n1 = d_cells_n1(idx);
            app.paired_cells(ii) = cells_n1;
            tmp = rp(1:2:end)';
            app.x_centroids(ii) = tmp(idx);
            tmp = rp(2:2:end)';
            app.y_centroids(ii) = tmp(idx);
            %
            % get IF objects into the final objects variables
            %
            ii = app.overlap_table.class_selection == 1;
            cells_n1 = app.overlap_table.IF_cells(ii);
            app.paired_cells(ii) = cells_n1;
            app.x_centroids(ii) = app.overlap_table.IF_X_centroid(ii);
            app.y_centroids(ii) = app.overlap_table.IF_Y_centroid(ii);
            %
            % get the SP objects for the rest in the final object variables
            %
            ii = (app.overlap_table.class_selection == 2 |...
                app.overlap_table.class_selection == 4 | ...
                app.overlap_table.class_selection == 5);
            cells_n1 = app.overlap_table.SP_cells(ii);
            app.paired_cells(ii) = cells_n1;
            app.x_centroids(ii) = app.overlap_table.SP_X_centroid(ii);
            app.y_centroids(ii) = app.overlap_table.SP_Y_centroid(ii);
            %
        end
        %
        function L = createBordermatrix(app, cells_n1)
            %%
            % create a label matrix L from the cells in cells_n
            %%
            %
            L = zeros(app.nRows, app.nCols, 'uint16');
            %
            for i1 = 1:length(cells_n1)
                idx = cells_n1{i1};
                if length(idx) == 1
                    continue
                end
                L(idx) = i1;
            end
            %
            % get boardering pixels by dilating and subtracting the cells
            % themselves
            %
            L = (imdilate(L,ones(3,3)) - L) > 0;
            L(:,:,2) = imfill(L,'holes')-L;
            L = L .* app.seg_col;
            %
        end
        %
        function convertOldTableformat(app)
            %%
            % convert old table format to the new format
            %%
            x = app.q;
            x.class_selection = x.include_cell;
            x.joint_overlap = x.jp;
            x.cell_check = zeros(height(x),1);
            x.flags = zeros(height(x),1);
            %
            % settings for the 80% overlap cells; look over 20% of these
            %
            ii = x.joint_overlap >= .8;
            x.cell_check(~ii) = 1;
            x.class_selection(ii) = 4;
            %
            ii = find(ii);
            n_overlaps = length(ii);
            r2 = (1:n_overlaps)';
            r = randsample(r2,floor(.8 * n_overlaps));
            %
            r3 = ~ismember(r2, r);
            x.cell_check(ii(r3)) = 2;
            x.class_selection(ii(r3)) = 0; 
            %
            x = x(:,{'IF_cellid','IF_X_centroid','IF_Y_centroid',...
                'IF_paired_w','ML_cellid','ML_X_centroid',...
                'ML_Y_centroid','ML_paired_w','cellid','IF_level',...
                'joint_overlap','class_selection','cell_check',...
                'flags'});
            x.Properties.VariableNames = {'IF_cellid','IF_X_centroid',...
                'IF_Y_centroid','SP_paired_w_IF','SP_cellid',...
                'SP_X_centroid','SP_Y_centroid','IF_paired_w_SP',...
                'pairid','IF_level','joint_overlap','class_selection',...
                'cell_check','flags'};
            app.q = x;
            %
        end
        %
        function select_previous_opt(app, ii)
            %%
            % set the push button to appropriate value and reselect cell
            % boundary
            %%
            %
            switch ii
                case 1
                    if app.A == 1
                        chgColors(app,app.AButton, 'on')
                    else
                        chgColors(app,app.BButton, 'on')
                    end
                    %
                case 2
                    if app.A == 2
                        chgColors(app,app.AButton, 'on')
                    else
                        chgColors(app,app.BButton, 'on')
                    end
                    %
                case 3
                    chgColors(app,app.DrawonDAPIButton, 'on')
                case 4
                    uialert(app.SegToolUI,...
                        'Unknown error in cell selection',...
                        'Error')
                case 5
                    chgColors(app,app.ABButton, 'on')
                case -1
                    chgColors(app,app.ArtifactButton, 'on')
                case -2
                    chgColors(app,app.NeitherButton, 'on')
            end
            %
            parse_flags(app)
            %
        end
        %
        function parse_flags(app)
            %%
            % parse the flags column to show for cell jumps
            %%
            t2 = app.overlap_table.flags(app.cell_idx);
            for i1 = 1:2
                t1 =  t2 ./ 2;
                t2 = floor(t1);
                t3 = t1 - t2;
                t4(:,i1) = t3 ~= 0; %#ok<AGROW> 
            end
            %
            if t4(1)
                chgColors(app,app.MultiNucButton, 'on')
            end
            %
            if t4(2)
                chgColors(app, app.FlagButton,'on')
            end
            %
        end
        %
        function measure_jo_cells(app)
            %%
            % determine the number of joint overlapped paired cells over .8, which were
            % sampled to review, that do no have A==B. If the number of pairs is over
            % the 20% limit then add the rest of the cells and alert the user.
            %%            
            ii = app.overlap_table.cell_check == 2 & ...
                app.overlap_table.class_selection ~= 5 &...
                app.overlap_table.class_selection ~= 0;
            %
            if length(find(ii)) > app.n_diffs_allowed
                %
                x = app.overlap_table;
                ii = x.class_selection == 4;
                vv = num2str(sum(ii));
                %
                msg = ['More than 20% of the sampled "highly ',...
                    'overlaping pairs" have disagreement. ',...
                    'The remaining ', vv,' cells ',...
                    'will now be added to the cell count'];
                 uialert(app.SegToolUI, msg, ...
                    'Info','Icon',...
                    'info','CloseFcn','uiresume(gcbf)');
                uiwait(gcbf)
                app.pgbar = uiprogressdlg(app.SegToolUI,'Title',...
                    'Please Wait','Message','Adding new pairs');
                %
                x.class_selection(ii) = 0;
                x.cell_check(ii) = 2;
                %
                ii1 = x.class_selection ~= 0;
                x.counter(ii1) = (1:sum(ii1))';
                %
                ii = x.class_selection == 0;
                x.counter(ii) = (sum(ii1) + 1: sum(ii1) + sum(ii))';
                %
                %ii = app.overlap_table.pairid(idx);
                %app.paired_cells(ii) = {0};
                %
                % reorder table on cell counter
                %
                app.overlap_table = x;
                %
                try
                    app.pgbar.Value = 1;
                    app.pgbar.Message = 'Finishing';
                    pause(.5)
                    close(app.pgbar);
                catch
                end
                app.n_diffs_allowed = -1;
                %
            end
            %
        end
        %
        function disp_images(app, nm, n1)
            %%
            % calls the image display for each image and renames if nm == 1
            %%
            if nm
                ff = app.fname;
                if nargin > 2
                    %
                    n1 = num2str(n1);
                    %
                    nall = sum(app.overlap_table.cell_check ~= 0);
                    nall = num2str(nall);
                    app.SegToolUI.Name = ['Segmentation Tool: ',...
                        ff, '; Detected Nuclear Object: ', n1, 'of', nall];
                else
                    app.SegToolUI.Name = ['Segmentation Tool: ',ff];
                end
                xl = [1, app.rr - app.ll];
                yl = [1, app.bb - app.tt];
                %
                r = randi([1, 2], 1);
                if r == 1
                    app.A = 1; % 1 for IF; 2 for SP
                    app.B = 2;
                else
                    app.B = 1;
                    app.A = 2;
                end
                %
            else
                xl = xlim(app.B1_Image);
                yl = ylim(app.B1_Image);
            end
            %
            imshow2(app, app.A1_Image, 1, app.A,xl,yl)
            imshow2(app, app.A2_Image, 2, app.A,xl,yl)
            %
            imshow2(app, app.B1_Image, 1, app.B,xl,yl)
            imshow2(app, app.B2_Image, 2, app.B,xl,yl)
            %
            linkaxes([app.A1_Image,app.A2_Image,...
                app.B1_Image,app.B2_Image], 'xy')
            %
        end
        %
        function imshow2(app, loc, layer, s_layer,xl,yl)
            %%
            % this function is used to change the cell show as well as its
            % apperance
            % loc: is the location parameters defined in the startupfnc
            % layer: is 1 for dapi, anything else is the emmbrane and dapi
            % s_layer: The image segmenation to display (IF or SP)
            % xl: x centroid
            % yl: y centroid
            %%
            C = app.nCols;
            R = app.nRows;
            %
            if layer ~= 1
                im = app.im_m;
            else
                im = app.im_d;
            end
            %
            im = reshape(im, R, C, 3);
            im = im(app.tt(1):app.bb(1),app.ll(1):app.rr(1),:);
            inp = app.cell_idx;
            %
            % default is for segmentation to be on
            %
            if app.ToggleSegButton.Value || app.AppliedSegButton.Value
                if s_layer == 1
                    if app.ToggleSegButton.Value
                        seg = app.seg_IF;
                    else
                        seg = app.applied_seg_im;
                        seg = buildSegMask(app, seg,...
                            app.overlap_table.IF_cells{inp});
                    end
                else
                    if app.ToggleSegButton.Value
                        seg = app.seg_SP;
                    else
                        seg = app.applied_seg_im;
                        seg = buildSegMask(app, seg,...
                            app.overlap_table.SP_cells{inp});
                    end
                end
                ii = seg > 0;
                aa = .5;
                if length(ii) > 1
                    im(ii) = (1-aa) .* im(ii) ...
                        + aa.* double(seg(ii));
                end
            end
            %
            im = uint8(im);
            %
            imshow(im, 'Parent', loc);
            xlim(loc, xl)
            ylim(loc, yl)
            %
            %xlim(loc, [app.ll, app.rr])
            %ylim(loc, [app.tt, app.bb])
            %
        end
        %       
        function apply_image_colors(app, dapi_logical, mem_logical)
            %%
            % scale the colors according to the slider and color
            % button selection
            %%
            %
            if dapi_logical
                app.a_d = .05 * app.ContrastSlider.Value;
                app.b_d = 4 * app.BrightnessSlider.Value;
                app.im_d =  app.b_d .* asinh(...
                    app.a_d .* app.mem_dapi_im(:,1)) * app.mycols(1,:);
            end
            %
            if mem_logical
                app.a_m = .05 * app.ContrastSlider.Value;
                app.b_m = 4 * app.BrightnessSlider.Value;
            end
            %
            app.im_m =  app.b_m .* asinh(...
                app.a_m .* app.mem_dapi_im(:,2)) * app.mycols(2,:);
            app.im_m = app.im_m + app.im_d;
            %
        end
        %
        function chgColors(~,hnd,step)
            %
            switch step
                case 'on'
                    hnd.BackgroundColor = 'g';
                    hnd.Value = 1;
                case 'off'
                    hnd.BackgroundColor = [.90 .90 .90];
                    hnd.Value = 0;
            end
            %
        end
        %
        function rstAllButtons(app, t)
            %
            chgColors(app,app.ABButton,'off')
            chgColors(app,app.BButton,'off')
            chgColors(app,app.AButton,'off')
            chgColors(app,app.NeitherButton,'off')
            chgColors(app,app.ArtifactButton,'off')
            chgColors(app,app.DrawonMemButton, 'off')
            chgColors(app,app.DrawonDAPIButton, 'off')
            %
            robot = java.awt.Robot;
            robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
            robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
            %
            % going to a new cell
            %
            if t == 1
                chgColors(app,app.FlagButton,'off')
                chgColors(app,app.MultiNucButton,'off')
                chgColors(app,app.ToggleSegButton,'on')
                app.ToggleSegButton.Value = 1;
                chgColors(app,app.AppliedSegButton,'off')
            end
            %
            % removing a cell
            %
            if (t == 2 ||t == 3 || t == 4) && ~isempty(app.n)
                %
                app.overlap_table.class_selection(app.cell_idx) = 0;
                id = app.overlap_table.pairid(app.cell_idx);
                
                %
                if ~isempty(app.paired_cells(id))
                    %
                    compile_applied_seg_map(app)
                    ii = app.draw_im == id;
                    app.draw_im(ii) = 0;
                    %
                end
                %
                app.paired_cells(id) = {0};
                %
                if ~isempty(app.cell_obj)
                    delete(app.cell_obj)
                end
                %
                % redisplay
                %
                if (app.AppliedSegButton.Value && t == 3) || t == 4
                    disp_images(app, 0);
                end
                %
            end
            %
        end
        %
        function draw_obj(app, loc, inp)
            %%
            % draw the cell object
            %%
            app.e = 0;
            if ~isempty(app.cell_obj)
                delete(app.cell_obj)
            end
            %
            pause(.5)
            app.cell_obj = drawfreehand(...
                loc, 'InteractionsAllowed','none',...
                'FaceAlpha', .2 , 'Multiclick', true);
            pause(.5)
            try
                mm = createMask(app.cell_obj);
            catch
                if ~isempty(app.cell_obj)
                    delete(app.cell_obj)
                end
                app.e = 1;
                return
            end
            %
            % map back to the image
            %
            [row, col] = find(mm > 0);
            row2 = row + app.tt(1) - 1;
            col2 = col + app.ll(1) - 1;
            idx = sub2ind([app.nRows,app.nCols],row2,col2);
            app.draw_im(idx) = inp;
            %
            app.paired_cells(inp) = {idx};
            compile_applied_seg_map(app)
            %
        end
        %
        function final_obj(app, final_t)
            %
            if final_t == 1
                uialert(app.SegToolUI, ['Last cell has been ',...
                    'reviewed for the image. ',...
                    'Whole image view will be displayed for a ',...
                    'final check.'], ...
                    'Finished','Icon',...
                    'success','CloseFcn','uiresume(gcbf)');
                uiwait(gcbf)
            else
                uialert(app.SegToolUI, ['Last cell has been ',...
                    'reviewed for the image. ',...
                    'Whole image view will be displayed instead of ',...
                    'single cell view.'], ...
                    'Finished','Icon',...
                    'success','CloseFcn','uiresume(gcbf)');
                uiwait(gcbf)
            end
            %
            app.hh2 = findall([app.SegToolUI],...
                '-property', 'Enable');
            set(app.hh2,'Enable', 'off')
            app.fullimageViewApp = fullimageView(app);
            waitfor(app.fullimageViewApp)
            app.addcell_instr = 1;
            set(app.hh2,'Enable', 'on')
            %
            figure(app.SegToolUI)
            if final_t == 1
                uialert(app.SegToolUI, ['Whole image reviewed. ',...
                    'Result will be saved'],'Finished','Icon',...
                    'success','CloseFcn','uiresume(gcbf)');
                uiwait(gcbf)
                saveProtocol(app)
            end
            chgColors(app, app.NextCellButton,'off')
            app.cell_idx = find(app.overlap_table.counter == ...
                max(app.overlap_table.counter), 1);
            %
            app.n = app.overlap_table.counter(app.cell_idx);
            ii = app.overlap_table.class_selection(app.cell_idx);
            rstAllButtons(app, 1)
            getSegmap(app)
            disp_images(app, 1, app.n);
            select_previous_opt(app, ii)
            %
            return
            %
        end
        %
        function compile_applied_seg_map(app)
            %
            x = app.overlap_table;
            tt1 = app.tt - 50;
            ll1 = app.ll - 50;
            bb1 = app.bb + 50;
            rr1 = app.rr + 50;
            %
            if tt1 < 1
                tt1 = 1;
            end
            if bb1 > app.nRows
                bb1 = app.nRows;
            end
            if ll1 < 1
                ll1 = 1;
            end
            if rr1 > app.nCols
                rr1 = app.nCols;
            end
            %
            im = zeros(app.nRows, app.nCols);
            im(tt1:bb1,ll1:rr1) = 1;
            idx = find(im);
            idx = find(cellfun(@(x) sum(ismember(x,idx)),app.paired_cells));
            gl = x.ground_level(idx);
            %
            mlow = min(x.ground_level);
            mhigh = max(x.ground_level);
            app.applied_seg_im = zeros(app.nRows, app.nCols);
            %
            for m_count = mlow:mhigh
                ii = gl == m_count;
                cells_n1 = app.paired_cells(idx(ii));
                im1 = createBordermatrix(app, cells_n1);
                app.applied_seg_im(im1(:,:,2) > 0) = 0;
                app.applied_seg_im(im1(:,:,1) > 0) = 1;
                %
            end
            %
            app.applied_seg_im = single(app.applied_seg_im)...
                .* app.seg_col;
        end
        %
        function del_object(app, loc)
           %%
           %
           %%
           app.e = 0;
            if ~isempty(app.cell_obj)
                delete(app.cell_obj)
            end
           %
           try
               pause(.5)
               app.cell_obj = drawpoint(...
                   loc, 'InteractionsAllowed','none');
               pause(.5)
               %
               row2 = floor(app.cell_obj.Position(2)) + app.tt(1) - 1;
               col2 = floor(app.cell_obj.Position(1)) + app.ll(1) - 1;
           catch
               app.e = 1;
               return
           end
           %
           idx = sub2ind([app.nRows,app.nCols],row2,col2);
           ii = cellfun(@(x) ismember(idx,x), app.paired_cells);
           idx2 = find(ii);
           if length(idx2) > 1
               uialert(app.SegToolUI,...
                   ['Selected point is in two cells, ',...
                   'no cells removed. Please remove one at a time.'],...
                   'Warning','Icon', 'warning')
               return
           elseif isempty(idx2)
               uialert(app.SegToolUI,...
                   ['Selected point is not in a cell, ',...
                   'no cells removed.'],...
                   'Warning','Icon', 'warning')
               return
           end
           %
           ind = app.overlap_table.pairid == idx2;
           app.overlap_table.class_selection(ind) = -3;
           %
           app.paired_cells(idx2) = {0};
           compile_applied_seg_map(app)
           %
           ii = app.draw_im == idx2;
           app.draw_im(ii) = 0;
           %
           if ~isempty(app.cell_obj)
               delete(app.cell_obj)
           end
           %
           % redisplay
           %
           disp_images(app, 0);
           %
        end
        %
        end
        %
    methods (Access = public)
        %
        function returnAddcell(app, overlap_table2, draw_im2, ...
                paired_cells2)
            app.overlap_table = overlap_table2;
            app.draw_im = draw_im2;
            app.paired_cells = paired_cells2;
        end
        %
        function returnDialog(app, closing_opts)
            app.closing_opts = closing_opts;
            switch app.closing_opts
                case 1
                    delete(app)
                case 2
                    app.e = 0;
                    saveProtocol(app)
                    if app.e == 0
                        delete(app)
                    else
                        set(app.hh2, 'Enable','on')
                    end
                otherwise
                    set(app.hh2, 'Enable','on')
            end
        end
        %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SegToolstartupFcn(app)
            %
            % Configure image axes
            %
            app.A1_Image = axes('Parent',app.Images_Panel);
            app.A1_Image.Box = 'on';
            app.A1_Image.XTick = [];
            app.A1_Image.XTickLabel = [];
            app.A1_Image.YTick = [];
            app.A1_Image.ZTick = [];
            app.A1_Image.Color = [1 1 1];
            app.A1_Image.Units = 'pixels';
            app.A1_Image.Position = [11 297 281 281];
            app.A1_Image.Visible = 'on';
            app.A1_Image.Colormap = gray(256);
            axis(app.A1_Image, 'image');
            %
            app.A2_Image = axes('Parent',app.Images_Panel);
            app.A2_Image.Box = 'on';
            app.A2_Image.XTick = [];
            app.A2_Image.XTickLabel = [];
            app.A2_Image.YTick = [];
            app.A2_Image.ZTick = [];
            app.A2_Image.Color = [1 1 1];
            app.A2_Image.Units = 'pixels';
            app.A2_Image.Position = [11 12 281 281];
            app.A2_Image.Visible = 'on';
            app.A2_Image.Colormap = gray(256);
            axis(app.A2_Image, 'image');
            %
            app.B1_Image = axes('Parent',app.Images_Panel);
            app.B1_Image.Box = 'on';
            app.B1_Image.XTick = [];
            app.B1_Image.XTickLabel = [];
            app.B1_Image.YTick = [];
            app.B1_Image.ZTick = [];
            app.B1_Image.Color = [1 1 1];
            app.B1_Image.Units = 'pixels';
            app.B1_Image.Position = [298 297 281 281];
            app.B1_Image.Visible = 'on';
            app.B1_Image.Colormap = gray(256);
            axis(app.B1_Image, 'image');
            %
            app.B2_Image = axes('Parent',app.Images_Panel);
            app.B2_Image.Box = 'on'; 
            app.B2_Image.XTick = [];
            app.B2_Image.XTickLabel = [];
            app.B2_Image.YTick = [];
            app.B2_Image.ZTick = [];
            app.B2_Image.Color = [1 1 1];
            app.B2_Image.Units = 'pixels';
            app.B2_Image.Position = [298 12 281 281];
            app.B2_Image.Visible = 'on';
            app.B2_Image.Colormap = gray(256);
            axis(app.B2_Image, 'image');
            %
            app.ContrastSlider.Value = 50;
            app.BrightnessSlider.Value = 50;
            %
        end

        % Value changed function: LoadImageButton
        function LoadImageButtonValueChanged(app, event)
            chgColors(app,app.LoadImageButton, 'on')
            %    
            % if app.n is not empty then there are results and they should
            % be saved before moving on to next image
            %
            saveProtocol(app)
            %
            hh = findall([app.SegToolUI], '-property', 'Enable');
            set(hh,'Enable', 'off')
            %
            % Display uigetfile dialog
            %
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            if isequal(f,0)
                uialert(app.SegToolUI,['User selected Cancel.',...
                   ' Please select a valid input file'],'Invalid File',...
                   'Icon','warning')
                set(hh,'Enable', 'on')
                figure(app.SegToolUI)
                return
            end
            figure(app.SegToolUI)
            %
            app.pgbar = uiprogressdlg(app.SegToolUI,'Title',...
                'Please Wait','Message',['checking file names ',...
                    'and file structure']);
            %
            imagefile = [p f];
            updateimage(app, imagefile);
            %
            try
                app.pgbar.Value = 1;
                app.pgbar.Message = 'Finishing';
                pause(.5)
                close(app.pgbar);
            catch
            end
            set(hh,'Enable', 'on')
            %
            chgColors(app,app.LoadImageButton, 'off')
        end

        % Close request function: SegToolUI
        function SegToolUICloseRequest(app, event)
            %
            app.hh2 = findall([app.SegToolUI], '-property', 'Enable');
            set(app.hh2,'Enable', 'off')
            %
            if ~isempty(app.overlap_table)
                %
                    app.ClosingDialogApp = ClosingDialog(app);
                    waitfor(app.ClosingDialogApp)
                %
                try
                    set(app.hh2,'Enable', 'on')
                catch
                end
                %
            else
                delete(app)
            end   
            %
        end

        % Value changed function: SaveTableButton
        function SaveTableButtonValueChanged(app, event)
            %
            set(app.SaveTableButton,'Enable', 'off')
            chgColors(app, app.SaveTableButton, 'on')
            saveProtocol(app)
            chgColors(app, app.SaveTableButton, 'off')
            set(app.SaveTableButton,'Enable', 'on')
            %
        end

        % Value changed function: DAPIButton
        function DAPIButtonValueChanged(app, event)
            %
            if app.DAPIButton.Value
                chgColors(app,app.MemButton,'off')
                chgColors(app,app.DAPIButton,'on')
                app.ContrastSlider.Value = app.a_d /.05;
                app.BrightnessSlider.Value = app.b_d / 4;
            else
                chgColors(app,app.DAPIButton,'off')               
            end
            %
        end

        % Value changed function: MemButton
        function MemButtonValueChanged(app, event)
            %
            if app.MemButton.Value
                chgColors(app,app.DAPIButton,'off')
                chgColors(app,app.MemButton,'on')
                app.ContrastSlider.Value = app.a_m /.05;
                app.BrightnessSlider.Value = app.b_m / 4;
            else
                chgColors(app,app.MemButton,'off')
            end
            %
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            %
            if ~isempty(app.overlap_table)
                %
                dapi_logical = app.DAPIButton.Value;
                mem_logical = app.MemButton.Value;
                apply_image_colors(app, dapi_logical, mem_logical)
                %
                disp_images(app, 0)
                %
            end
            %
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            %
            if ~isempty(app.overlap_table)
                %
                dapi_logical = app.DAPIButton.Value;
                mem_logical = app.MemButton.Value;
                apply_image_colors(app, dapi_logical, mem_logical)
                %
                disp_images(app, 0)
                %
            end
            %
        end

        % Value changed function: ToggleSegButton
        function ToggleSegButtonValueChanged(app, event)
            %
            if app.ToggleSegButton.Value
                chgColors(app,app.AppliedSegButton,'off')
                chgColors(app,app.ToggleSegButton,'on')
            else
                chgColors(app,app.ToggleSegButton,'off')
            end
            %
            if ~isempty(app.overlap_table)    
                disp_images(app, 0)
            end
            %
        end

        % Value changed function: AppliedSegButton
        function AppliedSegButtonValueChanged(app, event)
            %
            if app.AppliedSegButton.Value
                chgColors(app,app.ToggleSegButton,'off')
                chgColors(app,app.AppliedSegButton,'on')
            else
                chgColors(app,app.AppliedSegButton,'off')
            end
            %
            if ~isempty(app.overlap_table)   
                disp_images(app, 0)
            end
            %
        end

        % Value changed function: AddcellButton
        function AddcellButtonValueChanged(app, event)
            %
            chgColors(app,app.AddcellButton, 'on')
            %
            if ~isempty(app.overlap_table)
                %
                app.hh2 = findall([app.SegToolUI],...
                    '-property', 'Enable');
                set(app.hh2,'Enable', 'off')
                app.AddcellApp = Addcell(app);
                waitfor(app.AddcellApp)
                compile_applied_seg_map(app);
                app.addcell_instr = 1;
                set(app.hh2,'Enable', 'on')
                %
                % remove error cells
                % 
                ii = cellfun(@isempty, app.overlap_table.SP_cells) & ...
                    cellfun(@isempty, app.overlap_table.IF_cells) & ...
                    app.overlap_table.class_selection == 0;
                if any(ii)
                    app.overlap_table.class_selection(ii) = -1;
                end
                %
                % update cell count
                %
                ii1 = app.overlap_table.class_selection ~= 0 & ...
                    app.overlap_table.class_selection ~= 4 ...
                    & app.overlap_table.cell_check ~= 0;
                app.overlap_table.counter(ii1) = (1:sum(ii1))';
                %
                ii = app.overlap_table.class_selection == 0;
                app.overlap_table.counter(ii) = ...
                    (sum(ii1) + 1: sum(ii1) + sum(ii))';
                %
                % update ui with new count and segmentation
                %
                disp_images(app, 1, app.n)
                %
            end
            %
            chgColors(app,app.AddcellButton, 'off')
            %
        end

        % Value changed function: NextCellButton
        function NextCellButtonValueChanged(app, event)
          %
          %chgColors(app, app.NextCellButton,'on')
            if ~isempty(app.overlap_table)
                %
                if app.n_diffs_allowed ~= -1
                    measure_jo_cells(app);
                end
                %
                app.cell_idx = find(...
                    app.overlap_table.class_selection == 0, 1);
                app.c_ground_level = 1;
                app.ObjectPlaneSpinner.Value = app.c_ground_level;
                %
                if isempty(app.cell_idx)
                    %
                    final_obj(app, 1)
                    return
                    %
                end
                %
                app.n = app.overlap_table.counter(app.cell_idx);
                rstAllButtons(app, 1)
                getSegmap(app)
                compile_applied_seg_map(app)
                disp_images(app, 1, app.n)
                %
            end
            chgColors(app, app.NextCellButton,'off')
            %
        end

        % Value changed function: forwardOnecellButton
        function forwardOnecellButtonValueChanged(app, event)
            %
            %chgColors(app, app.forwardOnecellButton,'on')
            if ~isempty(app.overlap_table)
                %
                if app.n_diffs_allowed ~= -1
                    measure_jo_cells(app);
                end
                %
                app.n = app.n + 1;
                if app.n > max(app.overlap_table.counter)
                    %
                    final_obj(app, 1)
                    app.n = max(app.overlap_table.counter);
                    %
                end
                %
                app.cell_idx = find(app.overlap_table.counter == app.n);
                ii = app.overlap_table.class_selection(app.cell_idx);
                app.c_ground_level = ...
                    app.overlap_table.ground_level(app.cell_idx);
                app.ObjectPlaneSpinner.Value = app.c_ground_level;
                %
                rstAllButtons(app, 1)
                getSegmap(app)
                compile_applied_seg_map(app)
                disp_images(app, 1, app.n);
                select_previous_opt(app, ii)
                %
            end
            chgColors(app, app.forwardOnecellButton,'off')
            %
        end

        % Value changed function: backOnecellButton
        function backOnecellButtonValueChanged(app, event)
            %
            %chgColors(app, app.backOnecellButton,'on')
            if ~isempty(app.overlap_table)
                %
                app.n = app.n - 1;
                if app.n < 1
                    app.n = 1;
                end
                %
                app.cell_idx = find(app.overlap_table.counter == app.n);
                ii = app.overlap_table.class_selection(app.cell_idx);
                app.c_ground_level = ...
                    app.overlap_table.ground_level(app.cell_idx);
                app.ObjectPlaneSpinner.Value = app.c_ground_level;
                %
                rstAllButtons(app, 1)
                getSegmap(app)
                compile_applied_seg_map(app)
                disp_images(app, 1, app.n);
                select_previous_opt(app, ii)
                %
            end
            chgColors(app, app.backOnecellButton,'off')
            %
        end

        % Value changed function: JumptocellButton
        function JumptocellButtonValueChanged(app, event)
            %
            %chgColors(app, app.JumptocellButton,'on')
            if ~isempty(app.overlap_table)
                %
                app.n = app.JumpCellButton.Value;
                if app.n < 1
                    app.n = 1;
                elseif app.n > max(app.overlap_table.counter)
                    app.n = max(app.overlap_table.counter);
                end
                %
                app.cell_idx = find(app.overlap_table.counter == app.n);
                ii = app.overlap_table.class_selection(app.cell_idx);                
                %
                rstAllButtons(app, 1)
                getSegmap(app)
                compile_applied_seg_map(app)
                disp_images(app, 1, app.n);
                select_previous_opt(app, ii)
            end
            chgColors(app, app.JumptocellButton,'off')
            %
        end

        % Value changed function: AButton
        function AButtonValueChanged(app, event)
            %
            if app.AButton.Value
                %
                % reset all pushed buttons for classification, set A to 
                % 'on'
                %
                rstAllButtons(app, 2)
                chgColors(app,app.AButton,'on')
                %
                % update chart for A (1 IF; 2 SP)
                %
                if ~isempty(app.n)
                    inp = app.cell_idx;
                    if app.A == 1
                        app.overlap_table.class_selection(inp) = 1;
                        level = app.overlap_table.IF_level(inp);
                        ii = app.im_IF(:,:,level + 1) == ...
                            app.overlap_table.IF_cellid(inp);
                    else
                        app.overlap_table.class_selection(inp) = 2;
                        ii = app.im_SP(:,:,1) ==...
                            app.overlap_table.SP_cellid(inp);
                    end
                    %
                    idx = label2idx(uint8(ii));
                    id = app.overlap_table.pairid(inp);
                    app.paired_cells(id) = idx;
                    compile_applied_seg_map(app)
                    %
                    if app.AppliedSegButton.Value
                        disp_images(app, 0);
                    end
                    %
                end
                %
            else
                rstAllButtons(app, 3)
            end
            %
        end

        % Value changed function: BButton
        function BButtonValueChanged(app, event)
            %
            if app.BButton.Value            
                %
                % reset all pushed buttons for classification, set B to 'on'
                %
                rstAllButtons(app, 2)
                chgColors(app,app.BButton,'on')
                %
                % update chart for B (1 IF; 2 SP)
                %
                if ~isempty(app.n)
                    inp = app.cell_idx;
                    if app.B == 1
                        app.overlap_table.class_selection(inp) = 1;
                        level = app.overlap_table.IF_level(inp);
                        ii = app.im_IF(:,:,level + 1) == ...
                            app.overlap_table.IF_cellid(inp);
                    else
                        app.overlap_table.class_selection(inp) = 2;
                        ii = app.im_SP(:,:,1) == app.overlap_table.SP_cellid(inp);
                    end
                    %
                    idx = label2idx(uint8(ii));
                    id = app.overlap_table.pairid(inp);
                    app.paired_cells(id) = idx;
                    compile_applied_seg_map(app)
                    %
                    if app.AppliedSegButton.Value
                        disp_images(app, 0);
                    end
                    %
                end
                %
            else
               rstAllButtons(app, 3)
            end
            %
        end

        % Value changed function: ABButton
        function ABButtonValueChanged(app, event)
            %
            if app.ABButton.Value   
                %
                % reset all pushed buttons for classification, set AB to 
                % 'on'
                %
                rstAllButtons(app, 2)
                chgColors(app,app.ABButton,'on')
                %
                % update chart for AB (1 IF; 2 SP) keep A seg
                %
                if ~isempty(app.n)
                    inp = app.cell_idx;
                    app.overlap_table.class_selection(inp) = 5;
                    ii = app.im_SP(:,:,1) == app.overlap_table.SP_cellid(inp);
                    %
                    idx = label2idx(uint8(ii));
                    id = app.overlap_table.pairid(inp);
                    app.paired_cells(id) = idx;
                    compile_applied_seg_map(app)
                    %
                    if app.AppliedSegButton.Value
                        disp_images(app, 0);
                    end
                    %
                end
                %
            else
                rstAllButtons(app, 3)
            end
            %
        end

        % Value changed function: NeitherButton
        function NeitherButtonValueChanged(app, event)
            %
            if app.NeitherButton.Value  
                %
                % reset all pushed buttons for classification, set Neither to 
                % 'on'
                %
                rstAllButtons(app, 3)
                chgColors(app,app.NeitherButton,'on')
                %
                % update chart for AB (1 IF; 2 SP) keep SP seg
                %
                if ~isempty(app.n)
                    inp = app.cell_idx;
                    app.overlap_table.class_selection(inp) = -1;
                end
                %
            else
                rstAllButtons(app, 2)
            end
            %
        end

        % Value changed function: ArtifactButton
        function ArtifactButtonValueChanged(app, event)
            %
            if app.ArtifactButton.Value
                %
                % reset all pushed buttons for classification, set 
                % artifact to 'on'
                %
                rstAllButtons(app, 3)
                chgColors(app,app.ArtifactButton,'on')
                %
                % update chart for AB (1 IF; 2 SP) keep SP seg
                %
                if ~isempty(app.n)
                    inp = app.cell_idx;
                    app.overlap_table.class_selection(inp) = -2;
                end
                %
            else
                rstAllButtons(app, 2)
            end
            %
        end

        % Value changed function: DrawonDAPIButton
        function DrawonDAPIButtonValueChanged(app, event)
            %
            if app.DrawonDAPIButton.Value
                %
                % reset all pushed buttons for classification, set 
                % DrawonDAPI to 'on'
                %
                rstAllButtons(app, 3)
                chgColors(app,app.DrawonDAPIButton, 'on')
                %
                % update table
                %
                if ~isempty(app.n)
                    %
                    inp = app.cell_idx;
                    app.overlap_table.class_selection(inp) = 3;
                    id  = app.overlap_table.pairid(inp);
                    %
                    % draw object
                    %
                    draw_obj(app, app.B1_Image, id)
                    if app.e == 1
                        uialert(app.SegToolUI,...
                            ['Cell did not close correctly ',...
                            'and will not be saved. Please try again.'],...
                            'Warning','Icon', 'warning')
                        rstAllButtons(app, 4)
                    end
                    %
                    if app.AppliedSegButton.Value
                        disp_images(app, 0);
                    end
                    %
                end
                %
            else
                rstAllButtons(app, 4)
                    %
                    robot = java.awt.Robot;
                    robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
                    robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
                    %
            end
            %
        end

        % Value changed function: DrawonMemButton
        function DrawonMemButtonValueChanged(app, event)
            %
            if app.DrawonMemButton.Value
                %
                % reset all pushed buttons for classification, set 
                % DrawonMem to 'on'
                %
                rstAllButtons(app, 4)
                chgColors(app,app.DrawonMemButton, 'on')
                %
                % update table
                %
                if ~isempty(app.n)
                    %
                    inp = app.cell_idx;
                    app.overlap_table.class_selection(inp) = 3;
                    id  = app.overlap_table.pairid(inp);
                    %
                    % draw object
                    %
                    draw_obj(app, app.B2_Image, id)
                    if app.e == 1
                        uialert(app.SegToolUI,...
                            ['Cell did not close correctly ',...
                            'and will not be saved. Please try again.'],...
                            'Warning','Icon', 'warning')
                        rstAllButtons(app, 4)                        
                    end
                    %
                    if app.AppliedSegButton.Value
                        disp_images(app, 0);
                    end
                    %
                end
                %
            else
                rstAllButtons(app, 4)
                   %
                    robot = java.awt.Robot;
                    robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
                    robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
                    %
            end
            %
        end

        % Value changed function: FlagButton
        function FlagButtonValueChanged(app, event)
            %
            if app.FlagButton.Value
                chgColors(app,app.FlagButton,'on')
                if ~isempty(app.n)
                    inp = app.cell_idx;
                    app.overlap_table.flags(inp) = ...
                        app.overlap_table.flags(inp) + 2;                 
                end
            else
                chgColors(app,app.FlagButton,'off')
                if ~isempty(app.n)
                    inp = app.cell_idx;
                    app.overlap_table.flags(inp) = ...
                        app.overlap_table.flags(inp) - 2;
                end
            end
            %
        end

        % Value changed function: MultiNucButton
        function MultiNucButtonValueChanged(app, event)
            %
            if app.MultiNucButton.Value
                chgColors(app,app.MultiNucButton, 'on')
                if ~isempty(app.n)
                    inp = app.cell_idx;
                    app.overlap_table.flags(inp) = ...
                        app.overlap_table.flags(inp) + 1;
                end
            else
                chgColors(app,app.MultiNucButton, 'off')
                 if ~isempty(app.n)
                    inp = app.cell_idx;
                    app.overlap_table.flags(inp) = ...
                        app.overlap_table.flags(inp) - 1;
                end
            end            
            %
        end

        % Value changed function: ObjectPlaneSpinner
        function ObjectPlaneSpinnerValueChanged(app, event)
            %
            app.c_ground_level = app.ObjectPlaneSpinner.Value;
            if ~isempty(app.n)
                app.overlap_table.ground_level(app.cell_idx)...
                    = app.c_ground_level;
                compile_applied_seg_map(app)
                %
                if app.AppliedSegButton.Value
                    disp_images(app, 0);
                end
                %
            end
            %
        end

        % Value changed function: TrashButton
        function TrashButtonValueChanged(app, event)
            %
            if app.TrashButton.Value
                %
                app.TrashButton.BackgroundColor = [0.55,0.40,0.40];
                                    
                %
                if ~isempty(app.n)
                    if ~app.AppliedSegButton.Value
                        chgColors(app,app.AppliedSegButton,'on')
                        chgColors(app,app.ToggleSegButton,'off')
                        disp_images(app, 0)
                    end
                    %
                    del_object(app, app.B1_Image)
                    %
                    if app.e == 1
                        uialert(app.SegToolUI,...
                            ['Some issue with remove cell.',...
                            ' Please try again.'],...
                            'Warning','Icon', 'warning')
                    end
                    %
                    disp_images(app, 0);
                    %
                end
                %
                app.TrashButton.BackgroundColor = [0.81,0.60,0.60];
                app.TrashButton.Value = 0;
                %
            else
                %
                app.TrashButton.BackgroundColor = [0.81,0.60,0.60];
                app.TrashButton.Value = 0;
                %
                robot = java.awt.Robot;
                robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
                robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
                %
            end
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegToolUI = uifigure('Visible', 'off');
            app.SegToolUI.Name = 'Segmentation Tool';
            app.SegToolUI.CloseRequestFcn = createCallbackFcn(app, @SegToolUICloseRequest, true);
            app.ALabel = uilabel(app.SegToolUI);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.SegToolUI);
            app.BLabel.Text = 'B';
            app.Selections_Panel = uipanel(app.SegToolUI);
            app.JumpCellButton = uieditfield(app.Selections_Panel, 'numeric');
            app.JumpCellButton.ValueDisplayFormat = '%.0f';
            app.Classification_Panel = uipanel(app.Selections_Panel);
            app.ClassificationLabel = uilabel(app.Classification_Panel);
            app.ClassificationLabel.Text = ' Classification';
            app.PanelLine = uilabel(app.Classification_Panel);
            app.PanelLine.Text = '________________________________';
            app.DrawonDAPIButton = uibutton(app.Classification_Panel, 'state');
            app.DrawonDAPIButton.ValueChangedFcn = createCallbackFcn(app, @DrawonDAPIButtonValueChanged, true);
            app.DrawonDAPIButton.Tooltip = {''};
            app.DrawonDAPIButton.Text = 'Draw on DAPI';
            app.DrawonMemButton = uibutton(app.Classification_Panel, 'state');
            app.DrawonMemButton.ValueChangedFcn = createCallbackFcn(app, @DrawonMemButtonValueChanged, true);
            app.DrawonMemButton.Tooltip = {''};
            app.DrawonMemButton.Text = 'Draw on Mem';
            app.ArtifactButton = uibutton(app.Classification_Panel, 'state');
            app.ArtifactButton.ValueChangedFcn = createCallbackFcn(app, @ArtifactButtonValueChanged, true);
            app.ArtifactButton.Tooltip = {''};
            app.ArtifactButton.Text = 'Artifact';
            app.NeitherButton = uibutton(app.Classification_Panel, 'state');
            app.NeitherButton.ValueChangedFcn = createCallbackFcn(app, @NeitherButtonValueChanged, true);
            app.NeitherButton.Tooltip = {''};
            app.NeitherButton.Text = 'Neither';
            app.AButton = uibutton(app.Classification_Panel, 'state');
            app.AButton.ValueChangedFcn = createCallbackFcn(app, @AButtonValueChanged, true);
            app.AButton.Tooltip = {''};
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.Classification_Panel, 'state');
            app.BButton.ValueChangedFcn = createCallbackFcn(app, @BButtonValueChanged, true);
            app.BButton.Tooltip = {''};
            app.BButton.Text = 'B';
            app.ABButton = uibutton(app.Classification_Panel, 'state');
            app.ABButton.ValueChangedFcn = createCallbackFcn(app, @ABButtonValueChanged, true);
            app.ABButton.Tooltip = {''};
            app.ABButton.Text = 'A = B';
            app.MultiNucButton = uibutton(app.Classification_Panel, 'state');
            app.MultiNucButton.ValueChangedFcn = createCallbackFcn(app, @MultiNucButtonValueChanged, true);
            app.MultiNucButton.Tooltip = {''};
            app.MultiNucButton.Text = 'Multi Nuc';
            app.FlagButton = uibutton(app.Classification_Panel, 'state');
            app.FlagButton.ValueChangedFcn = createCallbackFcn(app, @FlagButtonValueChanged, true);
            app.FlagButton.Tooltip = {''};
            app.FlagButton.Text = 'Flag';
            app.AddcellButton = uibutton(app.Classification_Panel, 'state');
            app.AddcellButton.ValueChangedFcn = createCallbackFcn(app, @AddcellButtonValueChanged, true);
            app.AddcellButton.Tooltip = {''};
            app.AddcellButton.Text = 'Add cell';
            app.TrashButton = uibutton(app.Classification_Panel, 'state');
            app.TrashButton.ValueChangedFcn = createCallbackFcn(app, @TrashButtonValueChanged, true);
            app.TrashButton.Tooltip = {'Remove an object by clickng on it in the top right panel.'};
            app.TrashButton.Text = '\_/';
            app.ObjectPlaneSpinnerLabel = uilabel(app.Classification_Panel);
            app.ObjectPlaneSpinnerLabel.Text = 'Object Plane';
            app.ObjectPlaneSpinner = uispinner(app.Classification_Panel);
            app.ObjectPlaneSpinner.RoundFractionalValues = 'on';
            app.ObjectPlaneSpinner.ValueDisplayFormat = '%.0f';
            app.ObjectPlaneSpinner.ValueChangedFcn = createCallbackFcn(app, @ObjectPlaneSpinnerValueChanged, true);
            app.ObjectPlaneSpinner.Tooltip = {'Moves the object in front of or behind other objects.'};
            app.ObjectPlaneSpinner.Value = 1;
            app.Display_Panel = uipanel(app.Selections_Panel);
            app.ContrastSliderLabel = uilabel(app.Display_Panel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ToggleSegButton = uibutton(app.Display_Panel, 'state');
            app.ToggleSegButton.ValueChangedFcn = createCallbackFcn(app, @ToggleSegButtonValueChanged, true);
            app.ToggleSegButton.Tooltip = {''};
            app.ToggleSegButton.Text = 'Toggle Seg';
            app.ContrastSlider = uislider(app.Display_Panel);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.BrightnessSliderLabel = uilabel(app.Display_Panel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Display_Panel);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.DisplayLabel = uilabel(app.Display_Panel);
            app.DisplayLabel.Text = ' Display';
            app.AppliedSegButton = uibutton(app.Display_Panel, 'state');
            app.AppliedSegButton.ValueChangedFcn = createCallbackFcn(app, @AppliedSegButtonValueChanged, true);
            app.AppliedSegButton.Tooltip = {''};
            app.AppliedSegButton.Text = 'Applied Seg';
            app.DAPIButton = uibutton(app.Display_Panel, 'state');
            app.DAPIButton.ValueChangedFcn = createCallbackFcn(app, @DAPIButtonValueChanged, true);
            app.DAPIButton.Tooltip = {''};
            app.DAPIButton.Text = 'DAPI';
            app.MemButton = uibutton(app.Display_Panel, 'state');
            app.MemButton.ValueChangedFcn = createCallbackFcn(app, @MemButtonValueChanged, true);
            app.MemButton.Tooltip = {''};
            app.MemButton.Text = 'Mem';
            app.SaveTableButton = uibutton(app.Selections_Panel, 'state');
            app.SaveTableButton.ValueChangedFcn = createCallbackFcn(app, @SaveTableButtonValueChanged, true);
            app.SaveTableButton.Text = 'Save Table';
            app.LoadImageButton = uibutton(app.Selections_Panel, 'state');
            app.LoadImageButton.ValueChangedFcn = createCallbackFcn(app, @LoadImageButtonValueChanged, true);
            app.LoadImageButton.Tooltip = {''};
            app.LoadImageButton.Text = 'Load new image';
            app.NextCellButton = uibutton(app.Selections_Panel, 'state');
            app.NextCellButton.ValueChangedFcn = createCallbackFcn(app, @NextCellButtonValueChanged, true);
            app.NextCellButton.Tooltip = {''};
            app.NextCellButton.Text = 'Next Cell';
            app.forwardOnecellButton = uibutton(app.Selections_Panel, 'state');
            app.forwardOnecellButton.ValueChangedFcn = createCallbackFcn(app, @forwardOnecellButtonValueChanged, true);
            app.forwardOnecellButton.Tooltip = {''};
            app.forwardOnecellButton.Text = '>';
            app.backOnecellButton = uibutton(app.Selections_Panel, 'state');
            app.backOnecellButton.ValueChangedFcn = createCallbackFcn(app, @backOnecellButtonValueChanged, true);
            app.backOnecellButton.Tooltip = {''};
            app.backOnecellButton.Text = '<';
            app.JumptocellButton = uibutton(app.Selections_Panel, 'state');
            app.JumptocellButton.ValueChangedFcn = createCallbackFcn(app, @JumptocellButtonValueChanged, true);
            app.JumptocellButton.Tooltip = {''};
            app.JumptocellButton.Text = 'Jump to cell:';
            app.Images_Panel = uipanel(app.SegToolUI);
            % Show the figure after all components are created
            app.SegToolUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegToolUI)

            % Execute the startup function
            runStartupFcn(app, @SegToolstartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegToolUI)
        end
    end
end